classdef refineGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SortingrefinerUIFigure         matlab.ui.Figure
        DevmatrixscalingButtonGroup    matlab.ui.container.ButtonGroup
        ScalingmultiplierEditFieldLabel  matlab.ui.control.Label
        ScalingmultiplierEditField     matlab.ui.control.NumericEditField
        LockunitscalingEditFieldLabel  matlab.ui.control.Label
        LockunitscalingEditField       matlab.ui.control.EditField
        DonotreassignButton            matlab.ui.control.StateButton
        ShowscalingsButton             matlab.ui.control.Button
        ScalingmultiplierSlider        matlab.ui.control.Slider
        DoneButton                     matlab.ui.control.Button
        DownButton                     matlab.ui.control.Button
        ShowdevMatrixButton            matlab.ui.control.Button
        LockoriginalButton             matlab.ui.control.StateButton
        UpButton                       matlab.ui.control.Button
        ScalingdirectionLabel          matlab.ui.control.Label
    end

 
 properties (Access = private)
  CallingApp
  d = [];
  p
  iterations = 1
  f = [];
 end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, packet)
   app.CallingApp = mainapp;
   app.p = packet;
   app.ShowdevMatrixButton.Enable = 'off';
   app.ShowscalingsButton.Enable = 'off';
   app.UpButton.UserData = 1;
   app.DownButton.UserData = -1;
        end

        % Button pushed function: DownButton, UpButton
        function DownButtonPushed(app, event)
   scaleDirection = event.Source.UserData;
   scaling = app.ScalingmultiplierEditField.Value;
   exception = str2num(app.LockunitscalingEditField.Value);
   if ~isempty(exception)
    if iscolumn(exception)
     exception = exception';
    end
    exception = string(exception);
    for ii = 1:length(exception)
     t = strcmp(app.p.s.clusters, string(exception(ii)));
     if ii == 1
      e = t;
     else
      e = t | e;
     end
    end
   else
    e = [];
   end
   
   if isempty(app.f) || ~ishandle(app.f)
    app.f = figure; set(app.f, 'Position',  [300, 100, 1350, 850]);
   end
   if app.LockoriginalButton.Value == 0
    u = app.p.rawSpikeIdx; w = app.p.rawSpikeWaves;
    plotCurrent = 0;
   else
    u = app.p.rawSpikeIdx(app.p.t.orphanBool{app.p.currentBatch});
    w = app.p.rawSpikeWaves(app.p.t.orphanBool{app.p.currentBatch},:,:);
    plotCurrent = 1;
   end
   
   if ~isempty(app.d) && app.DonotreassignButton.Value
    lockedIdx = ismember(app.d.clustersPrev, str2double(app.p.s.clusters(e)));
    lockedVal = app.d.clustersPrev(lockedIdx);
   end
   [app.d] = SimpleCluster_refine2(app.p.m, app.p.s, app.p.t, ...
    u, w, app.p.currentBatch, scaleDirection, scaling, e, app.d);
   if exist('lockedIdx','var') && app.DonotreassignButton.Value
    app.d.spikeClusters(lockedIdx) = lockedVal;
   end
   refinePlot(app.p.xi, app.p.m, app.p.s, app.p.t, u, w, app.p.currentBatch, e, app.f, plotCurrent, app.d);
   app.ShowdevMatrixButton.Enable = 'on';
   app.ShowscalingsButton.Enable = 'on';
   app.iterations = app.iterations + 1;
        end

        % Close request function: SortingrefinerUIFigure
        function SortingrefinerUIFigureCloseRequest(app, event)
   switchButtons(app.CallingApp,1);
   switchButtons(app.CallingApp,3);
   close all
   delete(app)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
   if ~isempty(app.d)
    if app.LockoriginalButton.Value == 1
     lockedClust = app.p.t.spikeClust{app.p.currentBatch};
     lockedClust(lockedClust == 0) = app.d.spikeClusters;
     app.d.spikeClusters = lockedClust;
    end
    updateUnits(app.CallingApp, app.d);
   end
   switchButtons(app.CallingApp,1);
   switchButtons(app.CallingApp,3);
   close all
   delete(app)
        end

        % Button pushed function: ShowdevMatrixButton
        function ShowdevMatrixButtonPushed(app, event)
   h = figure; ax = axes;
%    [X, Y] = meshgrid(1:size(app.d.devMatrix,2), 1:size(app.d.devMatrix,1));
%    surf(ax, X, Y, app.d.devMatrix,'EdgeColor','none');
    for ii=1:size(app.d.devMatrix,2)
	devScaled(:,ii)=app.d.devMatrix(:,ii)*app.d.scaleArray(ii); %scale the deviation matrix
    end
   devScaled(devScaled < app.p.m.oldTempSTDThreshold^2) = 0;
   imagesc(ax, log10(devScaled))
   set(h, 'Position',  [400, 400, 600, 500]);
%    set(ax,'zscale','log')
%    set(ax,'ColorScale','log')
   hold on;
   %    Z = 1.5^2*ones(size(app.d.devMatrix));
   %    surf(ax, X,Y,Z,'FaceAlpha',0.7,'EdgeColor','none');
   colorbar;
%    view(ax, 2);
   
   ylabel(ax, 'Spike number');
   xlabel(ax, 'Cluster number');
   zlabel(ax, 'Deviation');
   title(ax, 'Deviation matrix surface');
   
        end

        % Value changed function: LockoriginalButton
        function LockoriginalButtonValueChanged(app, event)
   app.d = [];
   
        end

        % Button pushed function: ShowscalingsButton
        function ShowscalingsButtonPushed(app, event)
   h = figure; ax = axes;
   line(ax, 1:length(app.d.scaleArray), app.d.scaleArray)
   set(h, 'Position',  [400, 400, 600, 500]);
   set(ax,'yscale','log')
   ylabel(ax, 'Scaling multiplier');
   xlabel(ax, 'Cluster number');
   xticks(1:length(app.d.scaleArray))
   title(ax, 'Scaling array values');
        end

        % Value changed function: ScalingmultiplierSlider
        function ScalingmultiplierSliderValueChanged(app, event)
            value = app.ScalingmultiplierSlider.Value;
            app.ScalingmultiplierEditField.Value = value;
        end

        % Value changed function: ScalingmultiplierEditField
        function ScalingmultiplierEditFieldValueChanged(app, event)
            value = app.ScalingmultiplierEditField.Value;
            app.ScalingmultiplierSlider.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SortingrefinerUIFigure = uifigure('Visible', 'off');
            app.SortingrefinerUIFigure.Name = 'Sorting refiner';
            app.SortingrefinerUIFigure.CloseRequestFcn = createCallbackFcn(app, @SortingrefinerUIFigureCloseRequest, true);
            app.DevmatrixscalingButtonGroup = uibuttongroup(app.SortingrefinerUIFigure);
            app.DevmatrixscalingButtonGroup.Title = 'Dev matrix scaling';
            app.ScalingmultiplierEditFieldLabel = uilabel(app.DevmatrixscalingButtonGroup);
            app.ScalingmultiplierEditFieldLabel.Text = 'Scaling multiplier';
            app.ScalingmultiplierEditField = uieditfield(app.DevmatrixscalingButtonGroup, 'numeric');
            app.ScalingmultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @ScalingmultiplierEditFieldValueChanged, true);
            app.ScalingmultiplierEditField.Value = 0.1;
            app.LockunitscalingEditFieldLabel = uilabel(app.DevmatrixscalingButtonGroup);
            app.LockunitscalingEditFieldLabel.Text = 'Lock unit scaling';
            app.LockunitscalingEditField = uieditfield(app.DevmatrixscalingButtonGroup, 'text');
            app.DonotreassignButton = uibutton(app.DevmatrixscalingButtonGroup, 'state');
            app.DonotreassignButton.Text = {'Do not reassign'; 'assigned spikes'};
            app.ShowscalingsButton = uibutton(app.DevmatrixscalingButtonGroup, 'push');
            app.ShowscalingsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowscalingsButtonPushed, true);
            app.ShowscalingsButton.Text = 'Show scalings';
            app.ScalingmultiplierSlider = uislider(app.DevmatrixscalingButtonGroup);
            app.ScalingmultiplierSlider.Limits = [0 1];
            app.ScalingmultiplierSlider.ValueChangedFcn = createCallbackFcn(app, @ScalingmultiplierSliderValueChanged, true);
            app.ScalingmultiplierSlider.Value = 0.1;
            app.DoneButton = uibutton(app.SortingrefinerUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.DownButton = uibutton(app.SortingrefinerUIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = '-1';
            app.ShowdevMatrixButton = uibutton(app.SortingrefinerUIFigure, 'push');
            app.ShowdevMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ShowdevMatrixButtonPushed, true);
            app.ShowdevMatrixButton.Text = 'Show devMatrix';
            app.LockoriginalButton = uibutton(app.SortingrefinerUIFigure, 'state');
            app.LockoriginalButton.ValueChangedFcn = createCallbackFcn(app, @LockoriginalButtonValueChanged, true);
            app.LockoriginalButton.Text = {'Reset & lock original'; 'spike assignments'};
            app.UpButton = uibutton(app.SortingrefinerUIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.UpButton.Text = '+1';
            app.ScalingdirectionLabel = uilabel(app.SortingrefinerUIFigure);
            app.ScalingdirectionLabel.Text = 'Scaling direction';
            % Show the figure after all components are created
            app.SortingrefinerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = refineGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SortingrefinerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SortingrefinerUIFigure)
        end
    end
end