classdef spikeSortGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIBase                        matlab.ui.Figure
        SessionMenu                   matlab.ui.container.Menu
        InitialiseMenu                matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        LoadtemplatesMenu             matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        AddtimestampMenu              matlab.ui.container.Menu
        UndoredoMenu                  matlab.ui.container.Menu
        TimelineMenu                  matlab.ui.container.Menu
        PreviousbatchMenu             matlab.ui.container.Menu
        NextbatchMenu                 matlab.ui.container.Menu
        UnitfrequencyMenu             matlab.ui.container.Menu
        ScrubthroughunitMenu          matlab.ui.container.Menu
        UnitMenu                      matlab.ui.container.Menu
        NewunitMenu                   matlab.ui.container.Menu
        AddspikeMenu                  matlab.ui.container.Menu
        ForceaddMenu                  matlab.ui.container.Menu
        RemovespikeMenu               matlab.ui.container.Menu
        SplitMenu                     matlab.ui.container.Menu
        MergeMenu                     matlab.ui.container.Menu
        AutosplitMenu                 matlab.ui.container.Menu
        RefinebatchMenu               matlab.ui.container.Menu
        FigureshortcutsMenu           matlab.ui.container.Menu
        LZoomMenu                     matlab.ui.container.Menu
        LResetMenu                    matlab.ui.container.Menu
        LPanMenu                      matlab.ui.container.Menu
        LPointerMenu                  matlab.ui.container.Menu
        TZoomMenu                     matlab.ui.container.Menu
        TResetMenu                    matlab.ui.container.Menu
        TPanMenu                      matlab.ui.container.Menu
        TPointerMenu                  matlab.ui.container.Menu
        MatchunityaxisMenu            matlab.ui.container.Menu
        OthersMenu                    matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        GridMain                      matlab.ui.container.GridLayout
        StatusLabel                   matlab.ui.control.EditField
        ControlsGrid                  matlab.ui.container.GridLayout
        ThresholdsGrid                matlab.ui.container.GridLayout
        ThresholdsLabel               matlab.ui.control.Label
        CurrentunitsLabel             matlab.ui.control.Label
        OldField                      matlab.ui.control.NumericEditField
        NewField                      matlab.ui.control.NumericEditField
        NewunitLabel                  matlab.ui.control.Label
        SpikedetectionEditFieldLabel  matlab.ui.control.Label
        AmpthresholdEditField         matlab.ui.control.NumericEditField
        FuzzyButton                   matlab.ui.control.StateButton
        TraceylimitLabel              matlab.ui.control.Label
        yLimLowField                  matlab.ui.control.NumericEditField
        yLimHighField                 matlab.ui.control.NumericEditField
        DatascrubGrid                 matlab.ui.container.GridLayout
        ScrubdataLabel                matlab.ui.control.Label
        PreviousbatchButton           matlab.ui.control.Button
        NextbatchButton               matlab.ui.control.Button
        AutosortButton                matlab.ui.control.Button
        uptoField                     matlab.ui.control.NumericEditField
        ResortbatchButton             matlab.ui.control.Button
        RefinebatchButton             matlab.ui.control.Button
        GotobatchButton               matlab.ui.control.Button
        GotobatchField                matlab.ui.control.NumericEditField
        UnitoptionsGrid               matlab.ui.container.GridLayout
        UnitmanipulationLabel         matlab.ui.control.Label
        DeleteunitButton              matlab.ui.control.Button
        AddspikeButton                matlab.ui.control.Button
        RemovespikeButton             matlab.ui.control.Button
        AutosplitButton               matlab.ui.control.Button
        AutosplitField                matlab.ui.control.NumericEditField
        SplitunitButton               matlab.ui.control.Button
        MergeButton                   matlab.ui.control.Button
        UnitscrubButton               matlab.ui.control.Button
        RemoveplottedButton           matlab.ui.control.Button
        SplitplottedButton            matlab.ui.control.Button
        NewunitButton                 matlab.ui.control.Button
        ForceaddButton                matlab.ui.control.Button
        AutosplitBatchButton          matlab.ui.control.Button
        UnitviewingGrid               matlab.ui.container.GridLayout
        SpikestotalLabel              matlab.ui.control.Label
        SpikeshownField               matlab.ui.control.NumericEditField
        PastbatchesLabel              matlab.ui.control.Label
        PastbatchesField              matlab.ui.control.NumericEditField
        UnitviewingLabel              matlab.ui.control.Label
        FuturebatchesLabel            matlab.ui.control.Label
        FuturebatchesField            matlab.ui.control.NumericEditField
        PlotallchButton               matlab.ui.control.StateButton
        OtheroptionsGrid              matlab.ui.container.GridLayout
        OthersLabel                   matlab.ui.control.Label
        CleanupButton                 matlab.ui.control.Button
        ShowallButton                 matlab.ui.control.Button
        UnitfreqButton                matlab.ui.control.Button
        ShowloadedtemplaatesButton    matlab.ui.control.Button
        LGrid                         matlab.ui.container.GridLayout
        LeftUnitDropDown              matlab.ui.control.DropDown
        LTab                          matlab.ui.container.TabGroup
        MainChLTab                    matlab.ui.container.Tab
        GridMainChLTab                matlab.ui.container.GridLayout
        LeftUnit                      matlab.ui.control.UIAxes
        AllChLTab                     matlab.ui.container.Tab
        RGrid                         matlab.ui.container.GridLayout
        RightUnitDropDown             matlab.ui.control.DropDown
        RTab                          matlab.ui.container.TabGroup
        MainChRTab                    matlab.ui.container.Tab
        GridMainChRTab                matlab.ui.container.GridLayout
        RightUnit                     matlab.ui.control.UIAxes
        AllChRTab                     matlab.ui.container.Tab
        InfoGrid                      matlab.ui.container.GridLayout
        BinaryEditField               matlab.ui.control.EditField
        BinaryEditFieldLabel          matlab.ui.control.Label
        SavenameEditFieldLabel        matlab.ui.control.Label
        SavenameEditField             matlab.ui.control.EditField
        BinarynamecopyButton          matlab.ui.control.Button
        SavenamecopyButton            matlab.ui.control.Button
        TTab                          matlab.ui.container.TabGroup
        MainChTTab                    matlab.ui.container.Tab
        GridMainChTTab                matlab.ui.container.GridLayout
        Trace                         matlab.ui.control.UIAxes
        AllChTTab                     matlab.ui.container.Tab
    end

 
 properties (Access = private)
  %   outputs
  m % contains metadata
  fid
  s % contains unit_, waves_, clusters
  t % contains rawSpikeIdx{}, spikeClust{}, orphanBool{}, spikeIdxInBatch_, spikeBatchNum_
  
  %   plotting
  xi
  msConvert
  cmap
  wavesInBatches
  pData = [];
  pAssigned = [];
  pUnassigned = [];
  pL = [];
  pR = [];
  pTL = [];
  pTR = [];
  spT
  spL
  spR
  tgT
  tgL
  tgR
  
  %   sorting
  numTemplates = 40;
  
  %   keeping track
  lastStep = [];
  currentBatch = 1;
  splitSizeList
  rawSpikeWaves
  savePath = [];
  numSpikesPlotted
  batchesPlotted
  
  %   popups
  initialisePop
  refinePop
  
 end
 
 methods (Access = public)
  
  function [] = switchButtons(app, opt)
   switch opt
    case 0
     app.InitialiseMenu.Enable = 'off';
     app.LoadMenu.Enable = 'off';
    case 1
     app.InitialiseMenu.Enable = 'on';
     app.LoadMenu.Enable = 'on';
     
    case 2
     app.AddspikeButton.Enable = 'off';
     app.AddspikeMenu.Enable = 'off';
     app.AutosplitBatchButton.Enable = 'off';
     app.AutosplitButton.Enable = 'off';
     app.AutosplitMenu.Enable = 'off';
     app.BinarynamecopyButton.Enable = 'off';
     app.CleanupButton.Enable = 'off';
     app.DeleteunitButton.Enable = 'off';
     app.ForceaddButton.Enable = 'off';
     app.ForceaddMenu.Enable = 'off';
     app.GotobatchButton.Enable = 'off';
     app.LeftUnitDropDown.Enable = 'off';
     app.LoadtemplatesMenu.Enable = 'off';
     app.MergeButton.Enable = 'off';
     app.MergeMenu.Enable = 'off';
     app.NewunitButton.Enable = 'off';
     app.NewunitMenu.Enable = 'off';
     app.NextbatchButton.Enable = 'off';
     app.NextbatchMenu.Enable = 'off';
     app.PreviousbatchButton.Enable = 'off';
     app.PreviousbatchMenu.Enable = 'off';
     app.RefinebatchButton.Enable = 'off';
     app.RefinebatchMenu.Enable = 'off';
     app.RemoveplottedButton.Enable = 'off';
     app.RemovespikeButton.Enable = 'off';
     app.RemovespikeMenu.Enable = 'off';
     app.ResortbatchButton.Enable = 'off';
     app.RightUnitDropDown.Enable = 'off';
     app.SaveMenu.Enable = 'off';
     app.SavenamecopyButton.Enable = 'off';
     app.ScrubthroughunitMenu.Enable = 'off';
     app.ShowallButton.Enable = 'off';
     app.ShowloadedtemplaatesButton.Enable = 'off';
     app.SplitMenu.Enable = 'off';
     app.SplitplottedButton.Enable = 'off';
     app.SplitunitButton.Enable = 'off';
     app.UndoredoMenu.Enable = 'off';
     app.UnitfreqButton.Enable = 'off';
     app.UnitfrequencyMenu.Enable = 'off';
     app.UnitscrubButton.Enable = 'off';
     app.AutosortButton.Enable = 'off';
    case 3
     app.AddspikeButton.Enable = 'on';
     app.AddspikeMenu.Enable = 'on';
     app.AutosplitBatchButton.Enable = 'on';
     app.AutosplitButton.Enable = 'on';
     app.AutosplitMenu.Enable = 'on';
     app.BinarynamecopyButton.Enable = 'on';
     app.CleanupButton.Enable = 'on';
     app.DeleteunitButton.Enable = 'on';
     app.ForceaddButton.Enable = 'on';
     app.ForceaddMenu.Enable = 'on';
     app.GotobatchButton.Enable = 'on';
     app.LeftUnitDropDown.Enable = 'on';
     app.LoadtemplatesMenu.Enable = 'on';
     app.MergeButton.Enable = 'on';
     app.MergeMenu.Enable = 'on';
     app.NewunitButton.Enable = 'on';
     app.NewunitMenu.Enable = 'on';
     app.NextbatchButton.Enable = 'on';
     app.NextbatchMenu.Enable = 'off';
     app.PreviousbatchButton.Enable = 'on';
     app.PreviousbatchMenu.Enable = 'on';
     app.RefinebatchButton.Enable = 'on';
     app.RefinebatchMenu.Enable = 'on';
     app.RemoveplottedButton.Enable = 'on';
     app.RemovespikeButton.Enable = 'on';
     app.RemovespikeMenu.Enable = 'on';
     app.ResortbatchButton.Enable = 'on';
     app.RightUnitDropDown.Enable = 'on';
     app.SaveMenu.Enable = 'on';
     app.SavenamecopyButton.Enable = 'on';
     app.ScrubthroughunitMenu.Enable = 'on';
     app.ShowallButton.Enable = 'on';
     app.ShowloadedtemplaatesButton.Enable = 'on';
     app.SplitMenu.Enable = 'on';
     app.SplitplottedButton.Enable = 'on';
     app.SplitunitButton.Enable = 'on';
     app.UndoredoMenu.Enable = 'on';
     app.UnitfreqButton.Enable = 'on';
     app.UnitfrequencyMenu.Enable = 'on';
     app.UnitscrubButton.Enable = 'on';
     app.AutosortButton.Enable = 'on';
   end
  end
  
  
  function [] = updateVars(app, m)
   %    user input data
   app.StatusLabel.Value = "Loading...";
   drawnow
   app.m = m;
   app.s = [];
   app.t = [];
   app.s.clusters = string.empty();
   app.t.orphanBool = cell(1,1);
   app.t.spikeClust = [];
   
   %%%LEGACY CHECKS%%%
   if ~isfield(m,'yscale')
    app.m.yscale = 1;
   end
   if ~isfield(app.m, 'el_cutL')
    app.m.el_cutL = 7000;
   end
   if ~isfield(app.m, 'el_cutH')
    app.m.el_cutH = app.m.el_cut;
    app.m = rmfield(app.m, 'el_cut');
   end
   %%%LEGACY CHECKS%%%
   
   %   import raw data
   app.fid = fopen([app.m.fP app.m.fN],'r');
   fileNotFound = 1;
   while fileNotFound
    while app.fid < 1
     [app.m.fN, app.m.fP] = uigetfile('*.bin',"Could not find/open binary file! Please reselect");
     
     figure(app.UIBase);
     app.fid = fopen([app.m.fP app.m.fN],'r');
    end
    fseek(app.fid,0,'eof');
    nFileSizeBytes = ftell(app.fid);
    if nFileSizeBytes ~= app.m.fileSizeBytes
     [app.m.fN, app.m.fP] = uigetfile('*.bin',"Could not find/open binary file! Please reselect");
     
     figure(app.UIBase);
     app.fid = fopen([app.m.fP app.m.fN],'r');
    else
     fileNotFound = 0;
    end
   end
   fseek(app.fid,0,'bof');
   
   %    derived data
   app.currentBatch = 1;
   app.splitSizeList = [m.splitSize*ones(1, floor(m.fileSizeBytes/(m.dbytes*m.nChans*m.splitSize))), inf];
   app.batchesPlotted = -round(app.PastbatchesField.Value):min([length(app.splitSizeList), round(app.FuturebatchesField.Value)]);
   app.msConvert = 1000/app.m.sRateHz;
   if ~isempty(app.m.postName)
    postName = "_"+app.m.postName;
   else
    postName = "";
   end
   
   %    update gui fields
   app.OldField.Value = app.m.oldTempSTDThreshold;
   app.NewField.Value = app.m.newTempSTDThreshold;
   app.AmpthresholdEditField.Value = app.m.ampThreshold;
   app.BinaryEditField.Value = app.m.fN;
   app.SavenameEditField.Value ="sorting_"+erase(app.m.fN,'.bin') +postName;
   app.LeftUnitDropDown.Items = "1";
   app.RightUnitDropDown.Items = "1";
   app.LeftUnitDropDown.Value = "1";
   app.RightUnitDropDown.Value = "1";
   
   %    read and plot data
   app.readFilter(0, 0)
   app.redrawTracePlot();
   app.redrawUnitPlots();
   app.switchButtons(3)
  end
  
  
  function [] = updateUnits(app, d)
   app.saveLast();
   if app.currentBatch == 1
    offset = 0;
   else
    offset = sum(app.splitSizeList(1:(app.currentBatch-1)));
   end
   
   if ~isrow(d.spikeClusters)
    d.spikeClusters = d.spikeClusters';
   end
   app.t.orphanBool{app.currentBatch} = ~logical(d.spikeClusters);
   app.t.spikeClust{app.currentBatch} = d.spikeClusters;
   for ii = app.s.clusters
    outSpikesInii = app.t.("spikeBatchNum_"+ii) == app.currentBatch;
    newSpikesInii = find(app.t.spikeClust{app.currentBatch} == str2double(ii));
    app.s.("unit_"+ii)(outSpikesInii) = [];
    app.s.("waves_"+ii)(outSpikesInii,:,:) = [];
    app.t.("spikeBatchNum_"+ii)(outSpikesInii) = [];
    app.t.("spikeIdxInBatch_"+ii)(outSpikesInii) = [];
    
    app.s.("unit_"+ii) = [app.s.("unit_"+ii) app.t.rawSpikeIdx{app.currentBatch}(newSpikesInii) + offset];
    app.s.("waves_"+ii) = [app.s.("waves_"+ii); app.rawSpikeWaves(newSpikesInii,:,:)];
    app.t.("spikeBatchNum_"+ii) = [app.t.("spikeBatchNum_"+ii) app.currentBatch*ones(1,length(newSpikesInii))];
    app.t.("spikeIdxInBatch_"+ii) = [app.t.("spikeIdxInBatch_"+ii) newSpikesInii];
   end
   app.unitRefactorer();
   app.redrawTracePlot();
   app.redrawUnitPlots();
   
   figure(app.UIBase);
  end
 end
 
 
 methods (Access = private)
  
  function [] = readFilter(app, offset, traceMove)
   %   read a batch of data of length splitSizeList samples and filter it.
   %   Extract spike times also
   app.StatusLabel.Value = "Reading data...";
   drawnow
   fseek(app.fid,offset,'cof');
   app.currentBatch = app.currentBatch + traceMove;
   x = app.m.yscale*fread(app.fid,[app.m.nChans, app.splitSizeList(app.currentBatch)], 'int16','l'); % little endian open
   app.m.el_f = fir1(app.m.el_flen,[app.m.el_cutH app.m.el_cutL]./(app.m.sRateHz/2));
   c = 1;
   app.xi = [];
   for ii=app.m.ech % filter each electrode channel in turns
    app.xi(c,:) = splitconv(x(ii,:),app.m.el_f);
    c = c+1;
   end
   yOffset = prctile(app.xi,50,2); %yoffset = mean(xi,2);
   app.xi = app.xi - yOffset(1:size(app.xi,1),:); % remove DC offset
   
   filtSTD=std(app.xi,0,2); %StD of the signal in real unit (mV)
   filtRMS=rms(app.xi,2);
   fprintf('Filtered Data [%i/%i]: \t STD: %.4f, \t RMS: %.4f \n', ...
    app.currentBatch, length(app.splitSizeList), filtSTD,filtRMS);
   
   [~, detectedSpikes] = spike_times2(app.xi(app.m.mainCh, 1:end-app.m.spikeWidth), app.m.ampThreshold, -1); % aligned to negative peak
   detectedSpikes = detectedSpikes(detectedSpikes > app.m.spikeWidth+1);
   nSpikes = length(detectedSpikes);
   app.t.rawSpikeIdx{app.currentBatch} = detectedSpikes;
   app.rawSpikeWaves=double.empty(0,2*app.m.spikeWidth+1,size(app.xi,1)); %raw waveswaves=[];
   
   if length(app.t.orphanBool) < app.currentBatch || isempty(app.t.orphanBool{app.currentBatch})
    app.t.orphanBool{app.currentBatch}=true(size(app.t.rawSpikeIdx{app.currentBatch}));
    app.t.spikeClust{app.currentBatch}=zeros(size(app.t.rawSpikeIdx{app.currentBatch}));
   end
   
   %    In the case of increased ampThreshold, find new indices for previously
   %    assigned spikes
   if length(app.t.orphanBool) >= app.currentBatch && length(detectedSpikes) > length(app.t.orphanBool{app.currentBatch})
    [~, spikeHOrder] = sort(app.xi(1,detectedSpikes));
    oldSpike2New = sort(spikeHOrder(1:length(app.t.orphanBool{app.currentBatch})));
    temp = true(1,length(detectedSpikes));
    for ii = app.s.clusters
     app.t.("spikeIdxInBatch_"+ii)(app.t.("spikeBatchNum_"+ii) == app.currentBatch) = ...
      oldSpike2New(app.t.("spikeIdxInBatch_"+ii)(app.t.("spikeBatchNum_"+ii) == app.currentBatch));
     temp(app.t.("spikeIdxInBatch_"+ii)(app.t.("spikeBatchNum_"+ii) == app.currentBatch)) = 0;
    end
    app.t.orphanBool{app.currentBatch} = temp;
    temp = double(~temp);
    if any(temp)
     temp(find(temp)) = app.t.spikeClust{app.currentBatch}(find(app.t.spikeClust{app.currentBatch}));
    end
    app.t.spikeClust{app.currentBatch} = temp;
   end
   
   for ii=1:nSpikes
    tempWave=app.xi(1:end, app.t.rawSpikeIdx{app.currentBatch}(ii)-app.m.spikeWidth:app.t.rawSpikeIdx{app.currentBatch}(ii)+app.m.spikeWidth);
    app.rawSpikeWaves=cat(1,app.rawSpikeWaves, permute(tempWave, [3 2 1])); % accumulate a wave
   end
   
   app.StatusLabel.Value = "Ready";
  end
  
  
  %% unit interaction
  function out = getMaxUnit(app,mod)
   %   get highest number unit then add mod to it to return
   out = string(max(str2double(app.LeftUnitDropDown.Items))+mod);
  end
  
  
  function [] = incDropDown(app)
   %   update dropdown for units to reflect addtion of new units
   app.LeftUnitDropDown.Items = [app.LeftUnitDropDown.Items app.getMaxUnit(1)];
   app.RightUnitDropDown.Items = app.LeftUnitDropDown.Items;
  end
  
  
  function [] = decDropDown(app, idxL)
   %   update dropdown for units to reflect deletion of units
   newItems = app.LeftUnitDropDown.Items(~idxL);
   app.LeftUnitDropDown.Value = newItems{end};
   if strcmp(app.RightUnitDropDown.Value, app.LeftUnitDropDown.Value)
    app.RightUnitDropDown.Value = newItems{end};
   end
   app.LeftUnitDropDown.Items = newItems;
   app.RightUnitDropDown.Items = newItems;
  end
  
  
  function UnitButtonPushed(app, src, ~)
   h = ancestor(src, 'figure');
   clickType = get(h, 'SelectionType');
   if strcmp(clickType, 'alt')
    if any(strcmp(app.RightUnitDropDown.Items, src.UserData))
     app.RightUnitDropDown.Value = src.UserData;
     app.RightUnitDropDownValueChanged();
    end
   else
    if any(strcmp(app.LeftUnitDropDown.Items, src.UserData))
     app.LeftUnitDropDown.Value = src.UserData;
     app.LeftUnitDropDownValueChanged();
    end
   end
  end
  
  
  %% draw on axes
  function [] = redrawUnitPlots(app)
   %%   redraw spikes for each unit
   app.StatusLabel.Value = "Redrawing units...";
   drawnow
   
   %    setup
   delete(app.pL); delete(app.pR); delete(app.pTL); delete(app.pTR);
   for ii = 1:4
    cla(app.spL(ii)); cla(app.spR(ii));
   end
   app.wavesInBatches = [];
   tempWaves = [];
   a = [app.LeftUnit app.RightUnit];
   b = ["<", ">"];
   c = [string(app.LeftUnitDropDown.Value), string(app.RightUnitDropDown.Value)];
   set(a(1),'UserData', []); % reset selected spikes in left unit
   
   %    ii == 1 is left unit, ii == 2 is right unit
   for ii = 1:2
    if isfield(app.s, "unit_"+c(ii)) && ~isempty(app.s.("unit_"+c(ii))) % if there are spikes in the unit
     %    find spikes in selected batches
     batchWaves = find(ismember(app.t.("spikeBatchNum_"+c(ii)), app.batchesPlotted+app.currentBatch));
     if ii == 1
      app.wavesInBatches = batchWaves;
     end
     tempWaves = app.s.("waves_"+c(ii))(batchWaves,:,:);
     
     %    find how many spikes there were in the last 3 batches
     last3Batches = [nnz(app.t.("spikeBatchNum_"+c(ii)) == app.currentBatch-2),...
      nnz(app.t.("spikeBatchNum_"+c(ii)) == app.currentBatch-1),...
      nnz(app.t.("spikeBatchNum_"+c(ii)) == app.currentBatch)];
     if app.currentBatch < 3
      last3Batches(1:(3-app.currentBatch)) = 0;
     end
     title(a(ii), string(length(app.s.("unit_"+c(ii)))) +" spikes total, " + last3Batches(1) + "/" + last3Batches(2)...
      + "/" + last3Batches(3) + " spikes -2/-1/0 batches ago")
     
     q = 1;
    elseif isfield(app.t,("importedTemplateMapping")) && any(strcmp(c(ii), app.t.importedTemplateMapping{2}(:,1)))
     tempWaves = app.t.("template_"+app.t.importedTemplateMapping{2}(strcmp(c(ii), app.t.importedTemplateMapping{2}(:,1)),2));
     title(a(ii), "TEMPLATE SPIKES ONLY")
     q = 0;
    else
     title(a(ii), "No spikes!")
    end
    
    %     If there are waveforms in the selected unit OR there are templates
    if ~isempty(tempWaves)
     tempUnit = app.t.rawSpikeIdx{app.currentBatch}(app.t.spikeClust{app.currentBatch} == str2double(c(ii)));
     %      If the unit size is over the number to be plotted
     if size(tempWaves,1) > round(app.numSpikesPlotted)
      tempWaves = tempWaves(end-round(app.numSpikesPlotted)+1:end,:,:);
      if ~isempty(app.wavesInBatches) && ii == 1
       app.wavesInBatches = app.wavesInBatches(end-round(app.numSpikesPlotted)+1:end);
      end
     end
     
     %      Draw units
     if ii == 1 % Left unit
      app.pL = line(a(ii), -app.m.spikeWidth:app.m.spikeWidth, tempWaves(:,:,app.m.mainCh)');
      if size(app.xi,1) > 0 && app.PlotallchButton.Value
       for jj = 1:size(app.xi,1)
        line(app.spL(jj), -app.m.spikeWidth:app.m.spikeWidth, tempWaves(:,:,jj)');
       end
      end
      if q % if there are spikes in the unit
       set(app.pL, 'ButtonDownFcn', {@lineSelected1,app.wavesInBatches}) % click on spikes callback
       app.pTL = line(app.Trace, tempUnit*app.msConvert, app.xi(app.m.mainCh,tempUnit),...
        'LineStyle', 'none', 'Marker', b(ii), 'Color', app.cmap(str2double(c(ii)),:));
       if size(app.xi,1) > 0 && app.PlotallchButton.Value
        for jj = 1:size(app.xi,1)
         line(app.spT(jj), -tempUnit*app.msConvert, app.xi(jj,tempUnit),...
          'LineStyle', 'none', 'Marker', b(ii), 'Color', app.cmap(str2double(c(ii)),:));
        end
       end
      end
     elseif ii == 2 % Right unit
      app.pR = line(a(ii), -app.m.spikeWidth:app.m.spikeWidth, tempWaves(:,:,app.m.mainCh)');
      if size(app.xi,1) > 0 && app.PlotallchButton.Value
       for jj = 1:size(app.xi,1)
        line(app.spR(jj), -app.m.spikeWidth:app.m.spikeWidth, tempWaves(:,:,jj)');
       end
      end
      if ~strcmp(c(1), c(2)) && q % if left and right units are different
       app.pTR = line(app.Trace, tempUnit*app.msConvert, app.xi(app.m.mainCh,tempUnit),...
        'LineStyle', 'none', 'Marker', b(ii), 'Color', app.cmap(str2double(c(ii)),:));
       if size(app.xi,1) > 0 && app.PlotallchButton.Value
        for jj = 1:size(app.xi,1)
         line(app.spT(jj), -tempUnit*app.msConvert, app.xi(jj,tempUnit),...
          'LineStyle', 'none', 'Marker', b(ii), 'Color', app.cmap(str2double(c(ii)),:));
        end
       end
      end
     end
     
     %      prettify axes
     yl(1) = min(min(tempWaves(:,:,app.m.mainCh)))-50; yl(2) = max(max(tempWaves(:,:,app.m.mainCh)))+50;
     if yl(2) - yl(1) > 800
      step = 100;
     else
      step = 50;
     end
     ylim(a(ii),yl);
     yticks(a(ii),50*floor(yl(1)/50):step:50*floor(yl(2)/50));
     set(a(ii), 'YGrid', 'on', 'XGrid', 'off')
    end
    
   end
   
   app.LeftUnitDropDown.FontColor = app.cmap(str2double(c(1)),:);
   app.RightUnitDropDown.FontColor = app.cmap(str2double(c(2)),:);
   app.StatusLabel.Value = "Ready";
   drawnow
   if isempty(app.lastStep)
    app.UndoredoMenu.Enable = 'off';
   else
    app.UndoredoMenu.Enable = 'on';
   end
   
   %    callback for selected spikes
   function lineSelected1(src, ~, w)
    lineIdx = find(app.pL == src);
    if strcmp(src.LineStyle, ':')
     src.LineStyle = '-';
     temp = get(app.LeftUnit, 'UserData');
     set(app.LeftUnit, 'UserData', temp(temp ~= w(lineIdx)));
    else
     src.LineStyle = ':';
     temp = get(app.LeftUnit, 'UserData');
     set(app.LeftUnit, 'UserData', [temp w(lineIdx)]);
    end
   end
   
  end
  
  %   function [] = updateTracePlot(app, reassign, clust)
  %    %   redraw batch time series in blue then overlay scatter for detected
  %    %   spikes
  %    app.StatusLabel.Value = "Updating data trace...";
  %    title(app.Trace,"Data trace (" + app.currentBatch + "/" + length(app.splitSizeList) + ...
  %     ")            Spikes assigned: " + nnz(app.t.spikeClust{app.currentBatch}) + "/" + length(app.t.rawSpikeIdx{app.currentBatch}));
  %
  %    delete(app.pUnassigned(reassign));
  %    app.pUnassigned(reassign) = [];
  %    set(app.pUnassigned(:), 'Marker', 'x');
  % %       set(app.pUnassigned, 'ButtonDownFcn', {@pointSelected1, app.Trace, app.pUnassigned})
  %    set(app.pUnassigned, 'ButtonDownFcn', @pointSelected1)
  %    set(app.Trace,'UserData', []);
  %    tempUnit = app.t.rawSpikeIdx{app.currentBatch}(app.t.orphanBool{app.currentBatch});
  %    tempUnit = tempUnit(reassign);
  %    line(app.Trace, tempUnit*app.msConvert, app.xi(app.m.mainCh,tempUnit), 'LineStyle', 'none', 'Marker', 'x', 'Color', app.cmap(clust,:));
  %    app.StatusLabel.Value = "Ready";
  %    if isempty(app.lastStep)
  %     app.UndoredoMenu.Enable = 'off';
  %    else
  %     app.UndoredoMenu.Enable = 'on';
  %    end
  %
  %    function pointSelected1(src, ~)
  %     pointIdx = find(app.pUnassigned == src);
  %     if strcmp(src.Marker, 'o')
  %      src.Marker = 'x';
  %      temp = get(app.Trace, 'UserData');
  %      set(app.Trace, 'UserData', temp(temp ~= pointIdx));
  %     else
  %      src.Marker = 'o';
  %      temp = get(app.Trace, 'UserData');
  %      set(app.Trace, 'UserData', [temp pointIdx]);
  %     end
  %    end
  %   end
  
  
  function [] = redrawTracePlot(app)
   %   redraw batch time series in blue then overlay scatter for detected
   %   spikes
   app.StatusLabel.Value = "Redrawing data trace...";
   cla(app.Trace);
   for ii = 1:4
    cla(app.spT(ii));
   end
   title(app.Trace,"Data trace (" + app.currentBatch + "/" + length(app.splitSizeList) + ...
    ")            Spikes assigned: " + nnz(app.t.spikeClust{app.currentBatch}) + "/" + length(app.t.rawSpikeIdx{app.currentBatch}));
   app.pData = line(app.Trace, app.msConvert*(1:size(app.xi,2)), app.xi(app.m.mainCh,:),'Color','b');
   
   tempSpikes = app.t.rawSpikeIdx{app.currentBatch}(app.t.orphanBool{app.currentBatch});
   if ~isempty(tempSpikes)
    x(1,:) = tempSpikes*app.msConvert;
    y(1,:) = app.xi(app.m.mainCh,tempSpikes);
    %    x(2,:) = nan;
    %    y(2,:) = nan;
    
    app.pUnassigned = line(app.Trace, x, y,'Color', 'k', 'Marker', 'x', 'LineStyle', 'none');
    xlim(app.Trace, [0 app.msConvert*size(app.xi,2)]);
    
    yl = ylim(app.Trace);
    if ~isinf(app.yLimLowField.Value)
     yl(1) = app.yLimLowField.Value;
    end
    if ~isinf(app.yLimHighField.Value)
     yl(2) = app.yLimHighField.Value;
    end
    ylim(app.Trace, yl);
    
    if size(app.xi,1) > 0 && app.PlotallchButton.Value
     for ii = 1:size(app.xi,1)
      cla(app.spT(ii));
      line(app.spT(ii), app.msConvert*(1:size(app.xi,2)), app.xi(ii,:),'Color','b');
      line(app.spT(ii), x, app.xi(ii,tempSpikes),'Color', 'k', 'Marker', 'x', 'LineStyle', 'none');
      xlim(app.spT(ii), [0 app.msConvert*size(app.xi,2)])
      ylim(app.spT(ii), yl);
     end
    end
    %    set(app.pUnassigned, 'ButtonDownFcn', @pointSelected1)
    %    set(app.Trace,'UserData', []);
   end
   
   %    dataRange = sort(app.xi(app.m.mainCh,:));
   %    maxOutlier = diff(dataRange(end-4:end)) > 200;
   %    minOutlier = diff(dataRange(1:5)) > 200;
   %    if nnz(maxOutlier) > 0
   %        yM(2) = dataRange(size(app.xi,2)-5+find(maxOutlier))+20;
   %    else
   %        yM(2) = max(app.xi(app.m.mainCh,:),[],2)+20;
   %    end
   %    if nnz(minOutlier) > 0
   %        yM(1) = dataRange(find(minOutlier)+1)-20;
   %    else
   %        yM(1) = min(app.xi(app.m.mainCh,:),[],2)-20;
   %    end
   %    ylim(app.Trace, yM)
   c = 1;
   for ii = app.s.clusters
    tempUnit = app.t.rawSpikeIdx{app.currentBatch}(app.t.spikeClust{app.currentBatch} == str2double(ii));
    if ~isempty(tempUnit)
     nii = str2double(ii);
     ms = app.getMarker(nii);
     app.pAssigned(c) = line(app.Trace, tempUnit*app.msConvert, app.xi(app.m.mainCh,tempUnit), 'LineStyle', 'none', 'Marker', ms, 'Color', app.cmap(nii,:));
     if size(app.xi,1) > 0 && app.PlotallchButton.Value
      for jj = 1:size(app.xi,1)
       line(app.spT(jj), tempUnit*app.msConvert, app.xi(jj,tempUnit), 'LineStyle', 'none', 'Marker', ms, 'Color', app.cmap(nii,:));
      end
     end
     c = c+1;
    end
   end
   app.StatusLabel.Value = "Ready";
   if isempty(app.lastStep)
    app.UndoredoMenu.Enable = 'off';
   else
    app.UndoredoMenu.Enable = 'on';
   end
   
   %    function pointSelected1(src, ~)
   %     pointIdx = find(app.pUnassigned == src);
   %     if strcmp(src.Marker, 'o')
   %      src.Marker = 'x';
   %      temp = get(app.Trace, 'UserData');
   %      set(app.Trace, 'UserData', temp(temp ~= pointIdx));
   %     else
   %      src.Marker = 'o';
   %      temp = get(app.Trace, 'UserData');
   %      set(app.Trace, 'UserData', [temp pointIdx]);
   %     end
   %    end
  end
  
  
  %% others
  function [] = addHotkeys(~, fig)
   %   add cool little hotkeys to breakout figures for easy navigation
   mymenu = uimenu('Parent',fig,'Label','Hot Keys');
   uimenu('Parent',mymenu,'Label','ZoomIn','Accelerator','1','Callback',@(src,evt)zoom(fig,'on'));
   uimenu('Parent',mymenu,'Label','ZoomOut','Accelerator','2','Callback',@(src,evt)zoom(fig,'out'));
   uimenu('Parent',mymenu,'Label','Pan','Accelerator','3','Callback',@(src,evt)pan(fig,'on'));
   uimenu('Parent',mymenu,'Label','Pointer','Accelerator','4','Callback',@(src,evt)pointer());
   
   function pointer()
    %    allowes a hotkey for reverting back to pointer
    zoom(fig,'off')
    pan(fig,'off')
   end
   
  end
  
  function [out, figData] = getFigData(app, f)
   figOpen = 1;
   while figOpen
    try
     w = waitforbuttonpress;
    catch
     figOpen = 0;
     figData = [];
     out = 0;
    end
    if ~ishandle(f)
     figOpen = 0;
     figData = [];
     out = 0;
    elseif w
     k = get(f, 'CurrentCharacter');
     if k == 13
      figOpen = 0;
      figData = get(f,'UserData');
      close(f);
      out = 1;
     end
    end
   end
   app.StatusLabel.Value = "Ready";
  end
  
  
  function [] = saveLast(app)
   app.lastStep.s = app.s;
   app.lastStep.m = app.m;
   app.lastStep.t = app.t;
   app.lastStep.currentBatch = app.currentBatch;
   app.lastStep.splitSizeList = app.splitSizeList;
   app.lastStep.msConvert = app.msConvert;
   app.lastStep.LI = app.LeftUnitDropDown.Items;
   app.lastStep.RI = app.RightUnitDropDown.Items;
   app.lastStep.LV = app.LeftUnitDropDown.Value;
   app.lastStep.RV = app.RightUnitDropDown.Value;
  end
  
  
  %% unit manipuation
  function [] = unitReassigner(app, n, u, w, b, i)
   app.s.("unit_"+n) = u;
   app.s.("waves_"+n) = w;
   app.t.("spikeBatchNum_"+n) = b;
   app.t.("spikeIdxInBatch_"+n) = i;
  end
  
  
  function [] = unitRemover(app, n)
   app.s = rmfield(app.s, "waves_"+n);
   app.s = rmfield(app.s, "unit_"+n);
   app.t = rmfield(app.t, "spikeBatchNum_"+n);
   app.t = rmfield(app.t, "spikeIdxInBatch_"+n);
   app.s.clusters = app.s.clusters(~strcmp(n,app.s.clusters));
   
   if isfield(app.t, 'importedTemplateMapping')
    newMapping = strcmp(n, app.t.importedTemplateMapping{2}(:,1));
    if any(newMapping)
     app.t.importedTemplateMapping{2}(newMapping,:) = [];
    end
   end
   for ii = 1:length(app.t.spikeClust)
    app.t.spikeClust{ii}(app.t.spikeClust{ii} == str2double(n)) = 0;
    app.t.orphanBool{ii}(app.t.spikeClust{ii} == str2double(n)) = 1;
   end
  end
  
  
  function [] = unitSplitter(app, n, I)
   tU = app.s.("unit_"+n);
   tW = app.s.("waves_"+n);
   tBN = app.t.("spikeBatchNum_"+n);
   tIIB = app.t.("spikeIdxInBatch_"+n);
   
   app.StatusLabel.Value = "Splitting unit...";
   drawnow
   app.LeftUnitDropDown.Items = [app.LeftUnitDropDown.Items app.getMaxUnit(1)];
   app.RightUnitDropDown.Items = app.LeftUnitDropDown.Items;
   app.s.clusters(length(app.s.clusters)+1) = app.getMaxUnit(0);
   
   %      new unit
   for ii = 1:length(app.t.spikeClust)
    inBatch = tBN(I) == ii;
    app.t.spikeClust{ii}(tIIB(I(inBatch))) = str2double(app.getMaxUnit(0));
   end
   app.unitReassigner(app.getMaxUnit(0), tU(I), tW(I,:,:), tBN(I), tIIB(I));
   
   %      old unit
   tW(I,:,:) = [];
   tU(I) = [];
   tBN(I) = [];
   tIIB(I) = [];
   app.unitReassigner(n, tU, tW, tBN, tIIB);
   
   if isfield(app.t, 'importedTemplateMapping')
    newMapping = strcmp(n, app.t.importedTemplateMapping{2}(:,1));
    if any(newMapping)
     app.t.importedTemplateMapping{2}(end+1,:) = [app.getMaxUnit(0) app.t.importedTemplateMapping{2}(newMapping,2)];
    end
   end
  end
  
  
  function [] = spikeRemover(app, n, I)
   app.saveLast();
   app.StatusLabel.Value = "Removing spikes...";
   drawnow
   tW = app.s.("waves_"+n);
   tU = app.s.("unit_"+n);
   tBN = app.t.("spikeBatchNum_"+n);
   tIIB = app.t.("spikeIdxInBatch_"+n);
   
   for ii = 1:length(app.t.orphanBool)
    inBatch = tBN(I) == ii;
    app.t.orphanBool{ii}(tIIB(I(inBatch))) = true;
    app.t.spikeClust{ii}(tIIB(I(inBatch))) = 0;
   end
   
   tU(I) = [];
   tW(I,:,:) = [];
   tBN(I) = [];
   tIIB(I) = [];
   app.unitReassigner(n, tU, tW, tBN, tIIB);
  end
  
  
  function [] = unitRefactorer(app)
   %   remove empty units, sort units and re-number units from 1
   if ~isempty(app.s.clusters)
    tempS.clusters = string.empty();
    tempT.spikeClust = app.t.spikeClust;
    if isfield(app.t, 'importedTemplateMapping')
     tempT.importedTemplateMapping{1} = app.t.importedTemplateMapping{1};
     tempT.importedTemplateMapping{2} = string.empty(0,2);
     for ii = tempT.importedTemplateMapping{1}(:,2)'
      tempT.("template_"+ii) = app.t.("template_"+ii);
     end
    end
    c = 1;
    minMean = zeros(1,length(app.s.clusters));
    for ii = app.s.clusters
     waveAmp = min(mean(app.s.("waves_"+ii)(:,:,app.m.mainCh),1));
     if ~isempty(waveAmp)
      minMean(c) = waveAmp;
     end
     c = c+1;
    end
    [~, clustMaxOrder] = sort(minMean);
    c = 1;
    for ii = app.s.clusters(clustMaxOrder)
     if isfield(app.t, 'importedTemplateMapping')
      newMapping = strcmp(ii, app.t.importedTemplateMapping{2}(:,1));
      if any(newMapping)
       tempT.importedTemplateMapping{2}(end+1,:) = [string(c) app.t.importedTemplateMapping{2}(newMapping,2)];
      end
     end
     
     if isfield(app.s, "unit_"+ii)
      [tempUnit, IA, ~] = unique(app.s.("unit_"+ii));
      tempS.("unit_"+c) = tempUnit;
      tempS.("waves_"+c) = app.s.("waves_"+ii)(IA,:,:);
      tempT.("spikeBatchNum_"+c) = app.t.("spikeBatchNum_"+ii)(IA);
      tempT.("spikeIdxInBatch_"+c) = app.t.("spikeIdxInBatch_"+ii)(IA);
      tempS.clusters(c) = string(c);
      
      if strcmpi(ii, app.LeftUnitDropDown.Value)
       newL = c;
      end
      if strcmpi(ii, app.RightUnitDropDown.Value)
       newR = c;
      end
      c = c + 1;
     end
    end
    
    for ii = 1:length(app.t.orphanBool)
     for jj = tempS.clusters
      tempI = tempT.("spikeBatchNum_"+jj) == ii;
      tempT.spikeClust{ii}(tempT.("spikeIdxInBatch_"+jj)(tempI)) = str2double(jj);
     end
     tempT.orphanBool{ii} = ~logical(tempT.spikeClust{ii});
    end
    tempT.rawSpikeIdx = app.t.rawSpikeIdx;
    app.s = tempS;
    app.t = tempT;
    app.LeftUnitDropDown.Items = app.s.clusters;
    app.RightUnitDropDown.Items = app.s.clusters;
    
    if ~exist('newL','var')
     newL = 1;
     newR = 1;
    end
    
    app.LeftUnitDropDown.Value = string(newL);
    app.RightUnitDropDown.Value = string(newR);
   end
   
  end
  
  
  function out = getMarker(app, n)
   switch rem(floor(n/(size(app.cmap,1)+1)),5)
    case 0
     out = "*";
    case 1
     out = "+";
    case 2
     out = "square";
    case 3
     out = "o";
    case 4
     out = "diamond";
   end
  end
  
 end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   app.switchButtons(2);
   
   app.cmap = distinguishable_colors(28);
   app.cmap = app.cmap([2:3, 5:18, 20:end],:);
   temp = cell(1,25);
   temp(:) = {'1x'};
   ColourGrid = uigridlayout(app.GridMain);
   ColourGrid.RowHeight = {'1x'};
   ColourGrid.ColumnWidth = temp;
   ColourGrid.ColumnSpacing = 0;
   ColourGrid.RowSpacing = 0;
   ColourGrid.Padding = [0 0 0 0];
   ColourGrid.Layout.Row = 4;
   ColourGrid.Layout.Column = 3;
   for ii = 1:25
    b.("UnitButton"+string(ii)) = uibutton(ColourGrid, 'push');
    b.("UnitButton"+string(ii)).ButtonPushedFcn = @app.UnitButtonPushed;
    b.("UnitButton"+string(ii)).Layout.Row = 1;
    b.("UnitButton"+string(ii)).Layout.Column = ii;
    b.("UnitButton"+string(ii)).Text = string(ii);
    b.("UnitButton"+string(ii)).BackgroundColor = app.cmap(ii,:);
    b.("UnitButton"+string(ii)).FontColor = [1 1 1];
    b.("UnitButton"+string(ii)).FontWeight = 'bold';
    b.("UnitButton"+string(ii)).UserData = string(ii);
   end
   
   %    buggy matlab workaround for tabs lol
   app.LTab.Layout.Row = 1;
   app.RTab.Layout.Row = 1;
   app.TTab.Layout.Row = 2;
   drawnow
   %    c.Position = [0,0,1,1];
   app.LTab.Layout.Row = 2;
   app.RTab.Layout.Row = 2;
   app.TTab.Layout.Row = 3;
   
   app.s.clusters = string.empty();
   app.t.orphanBool = cell(1,1);
   app.t.spikeClust = [];
   app.numTemplates = 40;
   app.LeftUnitDropDown.FontColor = app.cmap(str2double(app.LeftUnitDropDown.Value),:);
   app.RightUnitDropDown.FontColor = app.cmap(str2double(app.RightUnitDropDown.Value),:);
   
   app.InitialiseMenu.Accelerator = 'i';
   app.SaveMenu.Accelerator = 's';
   app.LoadMenu.Accelerator = 'l';
   app.LoadtemplatesMenu.Accelerator = 'k';
   app.UndoredoMenu.Accelerator = 'z';
   app.TZoomMenu.Accelerator = 'q';
   app.TResetMenu.Accelerator = 'w';
   app.TPanMenu.Accelerator = 'e';
   app.TPointerMenu.Accelerator = 'r';
   app.LZoomMenu.Accelerator = '1';
   app.LResetMenu.Accelerator = '2';
   app.LPanMenu.Accelerator = '3';
   app.LPointerMenu.Accelerator = '4';
   app.AddspikeMenu.Accelerator = 'd';
   app.ForceaddMenu.Accelerator = 'g';
   app.RemovespikeMenu.Accelerator = 'f';
   app.SplitMenu.Accelerator = 'c';
   app.MergeMenu.Accelerator = 'v';
   app.AddtimestampMenu.Accelerator = 't';
   app.HelpMenu.Accelerator = 'h';
   app.MatchunityaxisMenu.Accelerator = 'm';
   app.NewunitMenu.Accelerator = 'n';
   app.AutosplitMenu.Accelerator = 'a';
   app.NextbatchMenu.Accelerator = 'p';
   app.PreviousbatchMenu.Accelerator = 'o';
   app.RefinebatchMenu.Accelerator = 'x';
   
   app.AddspikeMenu.UserData = 1;
   app.AddspikeButton.UserData = 1;
   app.ForceaddMenu.UserData = 2;
   app.ForceaddButton.UserData = 2;
   
   %    allChSubplots
   %    subplotTrace = @(m,n,p) subtightplot (m, n, p, [0 0.02], [0.05 0.04], [0.02 0.02], 'Parent', app.AllChTTab);
   %    subplotTempR = @(m,n,p) subtightplot (m, n, p, [0.02 0.02], [0.05 0.04], [0.05 0.05], 'Parent', app.AllChRTab);
   %    subplotTempL = @(m,n,p) subtightplot (m, n, p, [0.02 0.02], [0.05 0.04], [0.05 0.05], 'Parent', app.AllChLTab);
   for ii = 1:4
    app.spT = tight_subplot(4,1, [0 0.02], [0.05 0.04], [0.02 0.02], app.AllChTTab);
    app.spR = tight_subplot(2,2, [0 0.02], [0.05 0.04], [0.02 0.02], app.AllChRTab);
    app.spL = tight_subplot(2,2, [0 0.02], [0.05 0.04], [0.02 0.02], app.AllChLTab);
    if ii == 1 || ii == 2
     set(app.spR(ii), 'XTickMode', 'manual', 'XTick', []);
     set(app.spL(ii), 'XTickMode', 'manual', 'XTick', []);
     set(app.spT(ii), 'XTickMode', 'manual', 'XTick', [], 'XColor', [0.5 0.5 0.5]);
    end
    if ii == 2 || ii == 4
     set(app.spR(ii), 'YTickMode', 'manual', 'YTick', []);
     set(app.spL(ii), 'YTickMode', 'manual', 'YTick', []);
    end
    if ii == 3
     set(app.spT(ii), 'XTickMode', 'manual', 'XTick', [], 'XColor', [0.5 0.5 0.5]);
    end
    hold(app.spT(ii), 'on');
   end
   hold(app.Trace, 'on');
   
        end

        % Menu selected function: InitialiseMenu
        function InitialiseButtonPushed(app, event)
   app.switchButtons(0);
   
   % Open the options dialog and pass inputs
   app.initialisePop = initialisePopup(app);
   
        end

        % Callback function: NewunitButton, NewunitMenu
        function NewunitButtonPushed(app, event)
   app.saveLast();
   app.StatusLabel.Value = "Adding unit...";
   drawnow
   app.incDropDown;
   app.LeftUnitDropDown.Value = app.getMaxUnit(0);
   title(app.LeftUnit, "");
   app.redrawUnitPlots();
        end

        % Button pushed function: DeleteunitButton
        function DeleteunitButtonPushed(app, event)
   if length(app.LeftUnitDropDown.Items) > 1
    app.saveLast();
    app.StatusLabel.Value = "Deleting unit...";
    drawnow
    value = app.LeftUnitDropDown.Value;
    idx = strcmp(value, app.LeftUnitDropDown.Items);
    if isfield(app.s,"unit_"+value)
     app.unitRemover(value);
    end
    
    app.decDropDown(idx);
    app.redrawTracePlot();
    app.redrawUnitPlots();
   else
    app.StatusLabel.Value = "Cannot delete the last unit";
   end
        end

        % Callback function: AddspikeButton, AddspikeMenu, 
        % ForceaddButton, ForceaddMenu
        function AddspikeButtonPushed(app, event)
   value = app.LeftUnitDropDown.Value;
   c = app.currentBatch;
   f = figure; set(gcf, 'Position',  [300, 200, 1200, 700]); %app.addHotkeys(f);
   ax = axes('NextPlot','add'); ylabel('Amplitude (uV)'); xlabel('Time (ms)');
   line(ax, app.msConvert*(1:size(app.xi,2)),app.xi(app.m.mainCh,:),'Color','b');
   hold on
   for ii = app.s.clusters
    if c == 1
     offset = 0;
    else
     offset = sum(app.splitSizeList(1:(app.currentBatch-1)));
    end
    tempUnit = app.s.("unit_"+ii) - offset;
    tempUnit = tempUnit(tempUnit > 0 & tempUnit < app.splitSizeList(c));
    if ~isempty(tempUnit)
     nii = str2double(ii);
     line(ax, tempUnit*app.msConvert, app.xi(app.m.mainCh,tempUnit), 'LineStyle', 'none', 'Marker', 'x', 'Color', app.cmap(nii,:));
    end
   end
   tempSpikes = app.t.rawSpikeIdx{app.currentBatch}(app.t.orphanBool{c});
   x(1,:) = tempSpikes*app.msConvert;
   %    x(2,:) = nan;
   y(1,:) = app.xi(app.m.mainCh,tempSpikes);
   %    y(2,:) = nan;
   h = line(ax, x, y,'Color', 'k', 'Marker', 'x', 'LineStyle', 'none');
   %    set(h, 'ButtonDownFcn', @pointSelected1)
   
   app.StatusLabel.Value = "Click on black spikes to add to unit, *HOTKEYS* 1: Zoom, 2: Zoom out, 3: Pan, 4: Pointer";
   drawnow
   
   I = [];
   count = 1;
   e = 0;
   while 1
    try
     if e
      I = []; break;
     end
     [x,y,b] = ginput(1);
     if isempty(b)
      close(f);
      break;
     elseif b == 1
      X=get(h,'XData'); Y=get(h,'YData');
      XScale=diff(get(ax,'XLim')); YScale=diff(get(ax,'YLim'));
      r=sqrt(((X -x)./XScale).^2+((Y-y)./YScale).^2);
      [mr,i]=min(r);
      if mr < 0.012
       idx = I == i;
       if ~nnz(idx)
        I = [I, i];
        p(count) = plot(X(i),Y(i),'Marker', 'o', 'Color', 'r');
        count = count+1;
       else
        delete(p(idx))
        p(idx) = []; I(idx) = [];
        count = count-1;
       end
      end
     else
      e = waiter();
     end
    catch
     I = []; break;
    end
   end
   
   %    [~, I] = app.getFigData(f);
   
   figure(app.UIBase);
   
   if ~isempty(I)
    app.saveLast();
    offset = sum(app.splitSizeList(1:(c-1)));
    templateWaves = app.rawSpikeWaves(app.t.orphanBool{c},:,:);
    templateWaves = templateWaves(I,:,:);
    
    if event.Source.UserData == 1
     if isfield(app.t,"template"+value)
      templateWaves = [app.t.("template"+value); templateWaves];
     end
     [app.s, app.t, newOrphanBool, ~] = newTemplateMatch2(app.m, app.s, app.t, app.rawSpikeWaves, app.t.rawSpikeIdx{app.currentBatch},...
      app.t.orphanBool{c}, str2double(value), app.currentBatch, templateWaves, offset, app.FuzzyButton.Value);
    elseif event.Source.UserData == 2
     [app.s, app.t, newOrphanBool] = forceAdd(app.s, app.t, I, app.t.rawSpikeIdx{app.currentBatch},...
      app.t.orphanBool{c}, str2double(value), app.currentBatch, offset, templateWaves);
    end
    
    app.t.spikeClust{c}(xor(newOrphanBool, app.t.orphanBool{c})) = str2double(value);
    app.t.orphanBool{c} = newOrphanBool;
    app.redrawTracePlot();
    app.redrawUnitPlots();
   end
   
   app.StatusLabel.Value = "Ready";
   
   function e = waiter()
    e = 0;
    while b == 49 || b == 50 || b == 51 || b == 52
     w = 0;
     switch b
      case 49 %1 on numpad
       zoom(ax,'on');
      case 50 %2 on numpad
       zoom(ax,'out');
      case 51 %3 on numpad
       pan(ax,'on');
      case 52 %4 on numpad
       pan(ax,'off');
       zoom(ax,'off');
       break;
     end
     while ~w
      w = waitforbuttonpress;
     end
     b = get(f, 'CurrentCharacter');
     if ~ishandle(f)
      e = 1;
      break;
     end
    end
   end
   
   %    I = get(app.Trace,'UserData');
   %    if ~isempty(I)
   %     app.saveLast();
   %     app.StatusLabel.Value = "Adding spikes...";
   %     drawnow
   %     offset = sum(app.splitSizeList(1:(c-1)));
   %     templateWaves = app.rawSpikeWaves(app.t.orphanBool{c},:);
   %     [app.s, app.t, newOrphanBool, ~] = newTemplateMatch2(app.s, app.t, app.rawSpikeWaves, app.t.rawSpikeIdx{app.currentBatch}, app.t.orphanBool{c}, ...
   %      str2double(value), app.currentBatch, templateWaves(I,:), app.m, offset);
   %     app.t.spikeClust{c}(xor(newOrphanBool, app.t.orphanBool{c})) = str2double(value);
   %     app.redrawUnitPlots();
   %     app.updateTracePlot(~newOrphanBool(app.t.orphanBool{c}), str2double(value));
   %     app.t.orphanBool{c} = newOrphanBool;
   %     app.StatusLabel.Value = "Ready";
   %    else
   %     app.StatusLabel.Value = "Select black spikes on trace to add to left unit";
   %    end
   
   %    function pointSelected1(src, ~)
   %     pointIdx = find(h == src);
   %     if strcmp(src.Marker, 'o')
   %      src.Marker = 'x';
   %      temp = get(f, 'UserData');
   %      set(f, 'UserData', temp(temp ~= pointIdx));
   %     else
   %      src.Marker = 'o';
   %      temp = get(f, 'UserData');
   %      set(f, 'UserData', [temp pointIdx]);
   %     end
        end

        % Callback function: RemovespikeButton, RemovespikeMenu
        function RemovespikeButtonPushed(app, event)
   value = app.LeftUnitDropDown.Value;
   if nnz(strcmp(value,app.s.clusters))
    I = get(app.LeftUnit,'UserData');
    if ~isempty(I)
     app.spikeRemover(value, I);
     app.redrawTracePlot();
     app.redrawUnitPlots();
    else
     app.StatusLabel.Value = "Select spikes on the left unit to remove";
    end
    
   else
    app.StatusLabel.Value = "Left unit is empty!";
   end
        end

        % Callback function: SplitMenu, SplitunitButton
        function SplitButtonPushed(app, event)
   value = app.LeftUnitDropDown.Value;
   if nnz(strcmp(value,app.s.clusters))
    I = get(app.LeftUnit, 'UserData');
    if ~isempty(I)
     app.saveLast();
     app.unitSplitter(value,I);
     app.redrawTracePlot();
     app.redrawUnitPlots();
    else
     app.StatusLabel.Value = "Select spikes on the left unit to split";
    end
   else
    app.StatusLabel.Value = "Left unit is empty!";
   end
        end

        % Callback function: AutosplitButton, AutosplitMenu
        function AutosplitButtonPushed(app, event)
   value = app.LeftUnitDropDown.Value;
   if nnz(strcmp(value,app.s.clusters))
    
    app.StatusLabel.Value = "Autosplitting left unit into " + string(app.AutosplitField.Value) + " units...";
    drawnow
    f = figure; %f1 = figure; app.addHotkeys(f);
    set(f, 'Position',  [300, 200, 1200, 700]);
    tempWaves = app.s.("waves_"+value);
    croppedWaves = tempWaves(:,ceil(size(tempWaves,2)/2) + (round(-0.2/app.msConvert):round(0.2/app.msConvert)),:);
    croppedWaves = reshape(croppedWaves, size(tempWaves,1), []);
    
    PC = pca(croppedWaves);
    PCwaves = croppedWaves*PC(:,1:3);
    clust = kmeans_opt(PCwaves,app.AutosplitField.Value);
    numClust = length(unique(clust));
    %     PCsp = subplot(1,numClust+1,numClust+1,'Parent',f1);
    
    ax = gobjects(numClust,1);
    yTemp = zeros(numClust,2);
    for ii = 1:numClust
     ax(ii) = subplot(1,numClust,ii,'Parent',f);
     axis square;
     plot(ax(ii), -app.m.spikeWidth:app.m.spikeWidth, tempWaves(clust==ii,:,app.m.mainCh)');
     xlabel(ax(ii), "Samples"); ylabel(ax(ii), "Amplitude (uV)");
     yTemp(ii,:) = ylim(ax(ii));
     
     if ii == 1
      title(ax(ii),"Template "+value, 'Color', app.cmap(str2double(value),:));
      %       scatter3(PCsp, PCwaves(clust==1,1),PCwaves(clust==1,2),PCwaves(clust==1,3),20,repmat(app.cmap(str2double(value),:),nnz(clust==1),1));
     else
      title(ax(ii),"Template "+str2double(app.getMaxUnit(ii-1)), 'Color', app.cmap(str2double(app.getMaxUnit(ii-1)),:))
      %       scatter3(PCsp, PCwaves(clust==ii,1),PCwaves(clust==ii,2),PCwaves(clust==ii,3),20,repmat(app.cmap(ii,:),nnz(clust==ii),1));
     end
     %      hold(PCsp);
    end
    
    sgtitle("Template splitting: ENTER to accept, close to reject")
    yTemp = [min(yTemp(:,1)), max(yTemp(:,2))];
    for ii = 1:numClust
     ylim(ax(ii), yTemp);
     axis(ax,'square');
    end
    %     xlabel(PCsp, "PC1");
    %     ylabel(PCsp, "PC2");
    %     zlabel(PCsp, "PC3");
    %     legend(PCsp);
    [yn, ~] = app.getFigData(f);
    if yn
     app.saveLast();
     truncClust = unique(clust);
     truncClust = truncClust(2:end);
     for ii = truncClust'
      app.unitSplitter(value, find(clust==ii));
      clust(clust == ii) = [];
     end
     app.redrawTracePlot();
     app.redrawUnitPlots();
    end
    
   else
    app.StatusLabel.Value = "Left unit is empty!";
   end
        end

        % Button pushed function: AutosplitBatchButton
        function AutosplitBatchButtonPushed(app, event)
   value = app.LeftUnitDropDown.Value;
   if nnz(strcmp(value,app.s.clusters))
    
    app.StatusLabel.Value = "Autosplitting left unit into " + string(app.AutosplitField.Value) + " units...";
    drawnow
    f = figure; %f1 = figure; app.addHotkeys(f);
    set(f, 'Position',  [300, 200, 1200, 700]);
    thisBatchBool = app.t.("spikeBatchNum_"+value) == app.currentBatch;
    tempWaves = app.s.("waves_"+value)(thisBatchBool,:,:);
    croppedWaves = tempWaves(:,ceil(size(tempWaves,2)/2) + (round(-0.2/app.msConvert):round(0.15/app.msConvert)),:);
    croppedWaves = reshape(croppedWaves, size(tempWaves,1), []);
    
    PC = pca(croppedWaves);
    PCwaves = croppedWaves*PC(:,1:3);
    clust = kmeans_opt(PCwaves,app.AutosplitField.Value);
    numClust = length(unique(clust));
    %     PCsp = subplot(1,numClust+1,numClust+1,'Parent',f1);
    
    ax = gobjects(numClust,1);
    yTemp = zeros(numClust,2);
    for ii = 1:numClust
     ax(ii) = subplot(1,numClust,ii,'Parent',f);
     axis square;
     if any(~thisBatchBool)
      line(ax(ii), -app.m.spikeWidth:app.m.spikeWidth, app.s.("waves_"+value)(~thisBatchBool,:,app.m.mainCh)', 'Color', [0.8, 0.8, 0.8]);
     end
     line(ax(ii), -app.m.spikeWidth:app.m.spikeWidth, tempWaves(clust==ii,:,app.m.mainCh)');
     xlabel(ax(ii), "Samples"); ylabel(ax(ii), "Amplitude (uV)");
     yTemp(ii,:) = ylim(ax(ii));
     
     if ii == 1
      title(ax(ii),"Template "+value, 'Color', app.cmap(str2double(value),:));
      %       scatter3(PCsp, PCwaves(clust==1,1),PCwaves(clust==1,2),PCwaves(clust==1,3),20,repmat(app.cmap(str2double(value),:),nnz(clust==1),1));
     else
      title(ax(ii),"Template "+str2double(app.getMaxUnit(ii-1)), 'Color', app.cmap(str2double(app.getMaxUnit(ii-1)),:))
      %       scatter3(PCsp, PCwaves(clust==ii,1),PCwaves(clust==ii,2),PCwaves(clust==ii,3),20,repmat(app.cmap(ii,:),nnz(clust==ii),1));
     end
     %      hold(PCsp);
    end
    
    sgtitle("Template splitting: ENTER to accept, close to reject")
    yTemp = [min(yTemp(:,1)), max(yTemp(:,2))];
    for ii = 1:numClust
     ylim(ax(ii), yTemp);
     axis(ax,'square');
    end
    %     xlabel(PCsp, "PC1");
    %     ylabel(PCsp, "PC2");
    %     zlabel(PCsp, "PC3");
    %     legend(PCsp);
    [yn, ~] = app.getFigData(f);
    if yn
     
     app.saveLast();
     truncClust = unique(clust);
     truncClust = truncClust(2:end);
     for ii = truncClust'
      thisBatchIdx = find(thisBatchBool);
      app.unitSplitter(value, thisBatchIdx(clust==ii));
      thisBatchBool(thisBatchIdx(clust==ii)) = [];
      clust(clust == ii) = [];
     end
     app.redrawTracePlot();
     app.redrawUnitPlots();
    end
    
   else
    app.StatusLabel.Value = "Left unit is empty!";
   end
        end

        % Callback function: MergeButton, MergeMenu
        function MergeButtonPushed(app, event)
   valueL = app.LeftUnitDropDown.Value;
   valueR = app.RightUnitDropDown.Value;
   if ~strcmp(valueL,valueR)
    if nnz(strcmp(valueL,app.s.clusters)) && nnz(strcmp(valueR,app.s.clusters))
     app.saveLast();
     app.StatusLabel.Value = "Merging units...";
     drawnow
     %        remove left unit and merge with right
     app.StatusLabel.Value = "Merging unit " + app.LeftUnitDropDown.Value + " into unit " + app.RightUnitDropDown.Value + "...";
     app.s.clusters = app.s.clusters(~strcmp(app.LeftUnitDropDown.Value,app.s.clusters));
     
     tempUnit = cat(2,app.s.("unit_"+valueL),app.s.("unit_"+valueR));
     [tempUnit, I] = sort(tempUnit);
     tempWaves = cat(1,app.s.("waves_"+valueL),app.s.("waves_"+valueR));
     tempBatch = cat(2,app.t.("spikeBatchNum_"+valueL),app.t.("spikeBatchNum_"+valueR));
     tempIIB = cat(2,app.t.("spikeIdxInBatch_"+valueL),app.t.("spikeIdxInBatch_"+valueR));
     tempWaves = tempWaves(I,:,:);
     tempBatch = tempBatch(I);
     tempIIB = tempIIB(I);
     app.unitReassigner(valueR, tempUnit, tempWaves, tempBatch, tempIIB);
     for ii = 1:length(app.t.spikeClust)
      app.t.spikeClust{ii}(app.t.spikeClust{ii} == str2double(valueL)) = str2double(valueR);
     end
     if isfield(app.t, 'importedTemplateMapping')
      LMapping = strcmp(valueL, app.t.importedTemplateMapping{2}(:,1));
      if any(LMapping)
       RMapping = strcmp(valueR, app.t.importedTemplateMapping{2}(:,1));
       if ~any(RMapping)
        app.t.importedTemplateMapping{2}(end+1,:) = [valueR, app.t.importedTemplateMapping{2}(LMapping,2)];
       end
       app.t.importedTemplateMapping{2}(LMapping,:) = [];
      end
     end
     app.unitRemover(valueL);
     
     idx = strcmp(valueL, app.LeftUnitDropDown.Items);
     app.decDropDown(idx);
     app.redrawTracePlot();
     app.redrawUnitPlots();
    end
   else
    app.StatusLabel.Value = "Left and right units are the same!";
   end
        end

        % Button pushed function: AutosortButton
        function AutosortButtonPushed(app, event)
   if ~isempty(app.s.clusters) && app.currentBatch > round(app.uptoField.Value)
    app.StatusLabel.Value = "Autosorting up to batch"+round(app.uptoField.Value);
    app.saveLast();
    if app.uptoField.Value > length(app.splitSizeList)
     r = length(app.splitSizeList)-app.currentBatch;
    else
     r = app.uptoField.Value-app.currentBatch;
    end
    for ii = 1:round(r)
     app.unitRefactorer();
     app.readFilter(0,1);
     offset = sum(app.splitSizeList(1:(app.currentBatch-1)));
     
     [app.s, app.t] = prevTemplateMatch2(app.m, app.s, app.t, app.m, app.t.rawSpikeIdx{app.currentBatch}, app.rawSpikeWaves, ...
      app.xi, app.currentBatch, offset);
    end
    
    app.redrawTracePlot();
    app.redrawUnitPlots();
    
   else
    app.StatusLabel.Value = "No units to sort into!";
   end
        end

        % Callback function: NextbatchButton, NextbatchMenu
        function NextButtonPushed(app, event)
   if app.currentBatch ~= length(app.splitSizeList)
    app.StatusLabel.Value = "Loading next batch of data...";
    drawnow
    app.saveLast();
    app.unitRefactorer();
    app.readFilter(0,1);
    app.redrawTracePlot();
    app.redrawUnitPlots();
   end
        end

        % Callback function: PreviousbatchButton, PreviousbatchMenu
        function PrevButtonPushed(app, event)
   if app.currentBatch ~= 1
    app.StatusLabel.Value = "Loading last batch of data...";
    drawnow
    app.saveLast();
    app.unitRefactorer();
    offset = -app.m.nChans*app.m.dbytes*(app.splitSizeList(app.currentBatch-1) + size(app.xi,2));
    app.readFilter(offset,-1);
    app.redrawTracePlot();
    app.redrawUnitPlots();
   end
        end

        % Button pushed function: GotobatchButton
        function GotobatchButtonPushed(app, event)
   value = round(app.GotobatchField.Value);
   if value ~= app.currentBatch
    app.StatusLabel.Value = "Loading new batch of data...";
    drawnow
    app.saveLast();
    if value < 1
     value = 1;
    elseif value > length(app.splitSizeList)
     value = length(app.splitSizeList);
    end
    offset = app.m.nChans*app.m.dbytes*sum(app.splitSizeList(1:(value-1)));
    app.unitRefactorer();
    fseek(app.fid,0,'bof');
    app.readFilter(offset,value - app.currentBatch);
    app.redrawTracePlot();
    app.redrawUnitPlots();
    app.StatusLabel.Value = "Ready";
   end
        end

        % Callback function: RefinebatchButton, RefinebatchMenu
        function RefinebatchButtonPushed(app, event)
   app.switchButtons(0);
   app.switchButtons(2);
   
   in.xi = app.xi;
   in.m = app.m;
   in.s = app.s;
   in.t = app.t;
   in.rawSpikeIdx = app.t.rawSpikeIdx{app.currentBatch};
   in.rawSpikeWaves = app.rawSpikeWaves;
   in.currentBatch = app.currentBatch;
   app.refinePop = refineGUI(app, in);
   app.StatusLabel.Value = "Refine window open!";
        end

        % Button pushed function: ResortbatchButton
        function ResortbatchButtonPushed(app, event)
   if ~isempty(app.s.clusters)
    app.StatusLabel.Value = "Re-sorting batch " + string(app.currentBatch) + "...";
    drawnow
    app.saveLast();
    app.unitRefactorer();
    offset = sum(app.splitSizeList(1:(app.currentBatch-1)));
    [app.s, app.t] = prevTemplateMatch2(app.m, app.s, app.t, app.t.rawSpikeIdx{app.currentBatch}, app.rawSpikeWaves, ...
     app.xi, app.currentBatch, offset);
    app.redrawTracePlot();
    app.redrawUnitPlots();
   else
    app.StatusLabel.Value = "No units to sort into!";
   end
        end

        % Menu selected function: SaveMenu
        function SaveButtonPushed(app, event)
   app.StatusLabel.Value = "Saving...";
   drawnow
   if app.savePath == 0
    app.savePath = pwd;
   end
   temp = uigetdir(app.savePath,'Select save path');
   
   figure(app.UIBase);
   if temp ~= 0
    app.savePath = temp;
    m = app.m;
    s = app.s;
    t = app.t;
    m.msec = app.m.sRateHz/1000;
    
    if app.AddtimestampMenu.Checked
     timeStamp = "_"+datestr(now,'yymmddHHMM');
    else
     timeStamp = "";
    end
    sN = string(app.SavenameEditField.Value)+timeStamp+".mat";
    save(app.savePath+"\"+sN,'m', 's', 't');
    app.StatusLabel.Value = "Saved! (" + sN + ")";
   else
    app.StatusLabel.Value = "Ready";
   end
        end

        % Button pushed function: ShowallButton
        function ShowallButtonButtonPushed(app, event)
   subplott = @(m,n,p) subtightplot (m, n, p, [0.03 0.03], [0.05 0.1], [0.05 0.05]);
   
   app.StatusLabel.Value = "Plotting all units...";
   drawnow
   sp = ceil(length(app.s.clusters)/6);
   allClust = [];
   f1 = figure; set(gcf, 'Position',  [200, 200, 900, 700]);
   ax = gobjects(length(app.s.clusters),1);
   yTemp = zeros(length(app.s.clusters),2);
   
   for ii=1:length(app.s.clusters)
    ax(ii) = subplott(sp,6,ii);
    tempWaves = app.s.("waves_"+app.s.clusters(ii))(:,:,app.m.mainCh);
    rp = randperm(size(tempWaves,1));
    if length(rp) > 600
     rp = rp(1:600);
    end
    tempWaves = tempWaves(sort(rp),:,:);
    p = line(ax(ii), -app.m.spikeWidth:app.m.spikeWidth, tempWaves');
    set(p, {'Color'}, num2cell(parula(size(tempWaves,1)),2));
    yTemp(ii,:) = ylim(ax(ii));
    allClust = cat(1,allClust, app.s.("waves_"+app.s.clusters(ii))(:,:,app.m.mainCh));
   end
   sgtitle('Units found - max 600 random spikes plotted');
   yTemp = [min(yTemp(:,1)), max(yTemp(:,2))];
   for ii = 1:length(app.s.clusters)
    ylim(ax(ii), yTemp);
    ylim(yTemp); %axis square;
    yticks(ax(ii), 200*floor(yTemp(1)/200):200:200*ceil(yTemp(2)/200));
    xlim(ax(ii), [-app.m.spikeWidth app.m.spikeWidth]);
    set(ax(ii),'xTick',[], 'YGrid', 'on', 'XGrid', 'off');
   end
   
   app.StatusLabel.Value = "Calculating PCAs...";
   f2 = figure; set(gcf, 'Position',  [1100, 200, 700, 600]);
   PC = pca(allClust);
   for ii=1:length(app.s.clusters)
    iiCmap=app.cmap(ii,:);
    ms = app.getMarker(ii);
    title(ax(ii), 'Unit '+app.s.clusters(ii)+" "+ ms +" ("+length(app.s.("unit_"+app.s.clusters(ii)))+")",'Color',iiCmap);
    clusPC = app.s.("waves_"+app.s.clusters(ii))(:,:,app.m.mainCh)*PC(:,1:3);
    scatter3(clusPC(:,1),clusPC(:,2),clusPC(:,3),20,repmat(iiCmap,size(clusPC,1),1),"Marker",ms);
    hold on;
   end
   xlabel("PC1");
   ylabel("PC2");
   zlabel("PC3");
   title('Units found - PCs view')
   legend("Unit " + app.s.clusters);
   app.StatusLabel.Value = "Ready";
        end

        % Menu selected function: LoadMenu
        function LoadButtonPushed(app, event)
   app.StatusLabel.Value = "Loading...";
   drawnow
   app.switchButtons(1)
   [fN, fP] = uigetfile('*.mat','Pick *.mat file to load');
   figure(app.UIBase);
   if fN ~= 0
    inFile = whos('-file',[fP fN]);
    if any(strcmp('m',{inFile(:).name})) && any(strcmp('s',{inFile(:).name})) && any(strcmp('t',{inFile(:).name}))
     load([fP fN],'m','s','t');
     if isfield(m,'fN') && isfield(m,'fP') && isfield(m,'fileSizeBytes') && isfield(m,'dbytes')...
       && isfield(m,'nChans') && isfield(m,'ech') && isfield(m,'mainCh') && isfield(m,'splitSize')...
       && isfield(m,'sRateHz') && isfield(m,'spikeWidth') && isfield(m,'ampThreshold')
      app.updateVars(m);
      t.rawSpikeIdx{app.currentBatch} = app.t.rawSpikeIdx{app.currentBatch};
      
      app.s = s;
      app.t = t;
      if ~isempty(app.s.clusters)
       app.LeftUnitDropDown.Items = app.s.clusters;
       app.RightUnitDropDown.Items = app.s.clusters;
       app.LeftUnitDropDown.Value = app.s.clusters(1);
       app.RightUnitDropDown.Value = app.s.clusters(1);
      else
       app.LeftUnitDropDown.Items = ["1"];
       app.RightUnitDropDown.Items = ["1"];
       app.LeftUnitDropDown.Value = "1";
       app.RightUnitDropDown.Value = "1";
      end
      app.redrawTracePlot();
      app.redrawUnitPlots();
      app.lastStep = [];
      app.StatusLabel.Value = "Ready";
     else
      app.StatusLabel.Value = "The structure 'm' does not contain the required fields!";
     end
    else
     app.StatusLabel.Value = "The structures 'm', 's' or 't' do not exist!";
    end
   else
    app.StatusLabel.Value = "Could not load file!";
   end
        end

        % Menu selected function: LoadtemplatesMenu
        function LoadtemplatesMenuSelected(app, event)
   if ~isfield(app.t,"importedTemplateOriginFileName")
    app.StatusLabel.Value = "Loading...";
    drawnow
    app.switchButtons(1)
    [fN, fP] = uigetfile('*.mat','Pick *.mat file to load as templates');
    
    msg = 'Choose template harvesting location from dataset:';
    title = 'Choose template harvesting location';
    answer = uiconfirm(app.UIBase,msg,title,...
     'Options',{'Start','End','Cancel'},...
     'DefaultOption',2,'CancelOption',3);
    
    if ~strcmp(answer,'CANCEL')
     if fN ~= 0
      inFile = whos('-file',[fP fN]);
      if any(strcmp('s',{inFile(:).name}))
       %       load([fP fN], 's', 't');
       load([fP fN], 's');
       if isfield(s,'clusters')
        %      t.rawSpikeIdx = app.t.rawSpikeIdx;
        %      t.spikeClust = app.t.spikeClust;
        %      t.orphanBool = app.t.orphanBool;
        app.t.importedTemplateOriginFileName = [fP, fN];
        app.t.importedTemplateMapping{2} = cat(2, string((1:length(s.clusters)) + str2double(app.getMaxUnit(0)))', string(1:length(s.clusters))');
        for ii = 1:length(s.clusters)
         app.t.("template_"+ii) = s.("waves_"+s.clusters(ii));
         app.s.("unit_"+app.getMaxUnit(ii)) = [];
         app.s.("waves_"+app.getMaxUnit(ii)) = [];
         app.t.("spikeBatchNum_"+app.getMaxUnit(ii)) = [];
         app.t.("spikeIdxInBatch_"+app.getMaxUnit(ii)) = [];
         
         app.t.importedTemplateMapping{1}(ii,:) = ["Template" string(ii) "Initalised from" "Unit" s.clusters(ii)];
         if size(app.t.("template_"+ii),1) > app.numTemplates
          if strcmpi(answer, 'End')
           app.t.("template_"+ii) = app.t.("template_"+ii)(end-app.numTemplates+1:end,:,:);
          elseif strcmpi(answer, 'Start')
           app.t.("template_"+ii) = app.t.("template_"+ii)(1:app.numTemplates,:,:);
          end
         end
        end
        
        if strcmpi(answer, 'End')
         app.t.importedTemplateMapping{1}(:,6) = "End";
        elseif strcmpi(answer, 'Start')
         app.t.importedTemplateMapping{1}(:,6) = "Start";
        end
        app.s.clusters = [app.s.clusters, string((1:length(s.clusters)) + str2double(app.getMaxUnit(0)))];
        app.LeftUnitDropDown.Items = app.s.clusters;
        app.RightUnitDropDown.Items = app.s.clusters;
        app.LeftUnitDropDown.Value = app.s.clusters(1);
        app.RightUnitDropDown.Value = app.s.clusters(1);
        app.redrawUnitPlots();
        app.lastStep = [];
        app.StatusLabel.Value = "Ready";
       else
        app.StatusLabel.Value = "The structure 's.clusters' does not exist!";
       end
      else
       app.StatusLabel.Value = "The structure 's' does not exist!";
      end
     else
      app.StatusLabel.Value = "Could not load file!";
     end
    end
   else
    app.StatusLabel.Value = "Templates have already been loaded in once!";
   end
        end

        % Button pushed function: CleanupButton
        function CleanupButtonPushed(app, event)
   if ~isempty(app.s.clusters) && length(app.LeftUnitDropDown.Items) > 1
    app.StatusLabel.Value = "Cleaning up units...";
    drawnow
    app.saveLast();
    app.unitRefactorer();
    app.redrawTracePlot();
    app.redrawUnitPlots();
   end
        end

        % Value changed function: SpikeshownField
        function SpikeshownFieldValueChanged(app, event)
   app.numSpikesPlotted = app.SpikeshownField.Value;
   app.redrawUnitPlots();
   
        end

        % Value changed function: LeftUnitDropDown
        function LeftUnitDropDownValueChanged(app, event)
   value = app.LeftUnitDropDown.Value;
   delete(app.pL);
   if nnz(strcmp(value,app.s.clusters))
    app.redrawUnitPlots();
   else
    app.LeftUnitDropDown.FontColor = app.cmap(str2double(value),:);
   end
        end

        % Value changed function: RightUnitDropDown
        function RightUnitDropDownValueChanged(app, event)
   value = app.RightUnitDropDown.Value;
   delete(app.pR);
   if nnz(strcmp(value,app.s.clusters))
    app.redrawUnitPlots();
   else
    app.LeftUnitDropDown.FontColor = app.cmap(str2double(value),:);
   end
        end

        % Close request function: UIBase
        function UIBaseCloseRequest(app, event)
   closeGUI();
   
   function closeGUI()
    msg = 'Exit DragonSort?';
    title = 'Confirm Exit';
    selection = uiconfirm(app.UIBase,msg,title,"Icon","warning");
    if strcmp(selection, 'OK')
     app.StatusLabel.Value = "Shutting down...";
     close all
     delete(app.initialisePop)
     delete(app.refinePop)
     delete(app)
    end
   end
        end

        % Callback function: ScrubthroughunitMenu, UnitscrubButton
        function UnitscrubButtonPushed(app, event)
   value = app.LeftUnitDropDown.Value;
   if nnz(strcmp(value,app.s.clusters))
    f = uifigure; app.addHotkeys(f); iiCmap=app.cmap(str2double(value),:);
    set(f, 'Position',  [300, 200, 800, 700]);
    ax = uiaxes(f, 'Position', [50 100 700 550], 'NextPlot', 'Add');
    tempWaves = app.s.("waves_"+value)(:,:,app.m.mainCh);
    
    p = line(ax, -app.m.spikeWidth:app.m.spikeWidth, tempWaves');
    set(p, {'Color'}, num2cell(parula(size(tempWaves,1)),2));
    if length(p) > 1
     set(p(2:end),'LineStyle', 'none');
    end
    title(ax, "Unit "+value,'Color',iiCmap);
    xlabel(ax, "Samples")
    ylabel(ax, "Amplitude (uV)")
    
    yl(1) = min(min(tempWaves))-50; yl(2) = max(max(tempWaves))+50;
    ylim(ax, yl);
    
    sldr = uislider(f,'Position',[50 80 700 3], 'Value',1, 'Limits',[1 size(tempWaves,1)],...
     'ValueChangingFcn',{@sliderMoving, p});
    btnS = uibutton(f, 'Text', 'Split here', 'Position',[100 20 200 22], 'ButtonPushedFcn', {@scrubSplit, sldr, f});
    btnD1 = uibutton(f, 'Text', 'Remove before', 'Position',[300 20 200 22], 'ButtonPushedFcn', {@scrubRemove, sldr, f, 0});
    btnD2 = uibutton(f, 'Text', 'Remove after', 'Position',[500 20 200 22], 'ButtonPushedFcn', {@scrubRemove, sldr, f, 1});
   else
    app.StatusLabel.Value = "Select a left unit to scrub through";
   end
   
   function sliderMoving(~, e, p)
    set(p(round(e.Value)+1:end), 'LineStyle', 'none');% delete(p(round(event.Value)+1:end));
    set(p(1:round(e.Value)), 'LineStyle', '-');
   end
   
   function scrubSplit(~, ~, sld, h)
    app.saveLast();
    n = app.LeftUnitDropDown.Value;
    tU = app.s.("unit_"+n);
    I = round(sld.Value)+1:length(tU);
    app.unitSplitter(n,I);
    app.redrawTracePlot();
    app.redrawUnitPlots();
    close(h)
   end
   
   function scrubRemove(~, ~, sld, h, o)
    app.saveLast();
    n = app.LeftUnitDropDown.Value;
    tU = app.s.("unit_"+n);
    if o
     I = round(sld.Value)+1:length(tU);
    else
     I = 1:round(sld.Value)+1;
    end
    app.spikeRemover(n,I);
    app.redrawTracePlot();
    app.redrawUnitPlots();
    close(h)
   end
        end

        % Menu selected function: UndoredoMenu
        function UndoredoMenuSelected(app, event)
   if ~isempty(app.lastStep)
    app.StatusLabel.Value = "Undoing...";
    drawnow
    q.s = app.s;
    q.m = app.m;
    q.t = app.t;
    q.currentBatch = app.currentBatch;
    q.splitSizeList = app.splitSizeList;
    q.msConvert = app.msConvert;
    q.LI = app.LeftUnitDropDown.Items;
    q.RI = app.RightUnitDropDown.Items;
    q.LV = app.LeftUnitDropDown.Value;
    q.RV = app.RightUnitDropDown.Value;
    
    app.s = app.lastStep.s;
    app.m = app.lastStep.m;
    app.t = app.lastStep.t;
    app.currentBatch = app.lastStep.currentBatch;
    app.splitSizeList = app.lastStep.splitSizeList;
    app.msConvert = app.lastStep.msConvert;
    app.LeftUnitDropDown.Items = app.lastStep.LI;
    app.RightUnitDropDown.Items = app.lastStep.RI;
    app.LeftUnitDropDown.Value = app.lastStep.LV;
    app.RightUnitDropDown.Value = app.lastStep.RV;
    
    offset = app.m.nChans*app.m.dbytes*sum(app.splitSizeList(1:(app.currentBatch-1)));
    fseek(app.fid,0,'bof');
    app.readFilter(offset,0);
    app.redrawTracePlot();
    app.redrawUnitPlots();
    app.lastStep = q;
   else
    app.StatusLabel.Value = "Last step inaccessable!";
    app.UndoredoMenu.Enable = 'off';
   end
        end

        % Menu selected function: LZoomMenu
        function LZoomMenuSelected(app, event)
   zoom(app.LeftUnit,'on');
        end

        % Menu selected function: LResetMenu
        function LResetMenuSelected(app, event)
   app.LeftUnit.XLimMode = 'auto';
   app.LeftUnit.YLimMode = 'auto';
        end

        % Menu selected function: LPanMenu
        function LPanMenuSelected(app, event)
   pan(app.LeftUnit,'on');
        end

        % Menu selected function: LPointerMenu
        function LPointerMenuSelected(app, event)
   zoom(app.LeftUnit,'off')
   pan(app.LeftUnit,'off')
        end

        % Menu selected function: TZoomMenu
        function TZoomMenuSelected(app, event)
   zoom(app.Trace, 'on');
   %     h.ActionPostCallback = @postZoom;
   %     h.RightClickAction = 'InverseZoom';
   
   %     function postZoom(~,~)
   %         drawnow;
   %     end
        end

        % Menu selected function: TResetMenu
        function TResetMenuSelected(app, event)
   app.Trace.XLimMode = 'auto';
   app.Trace.YLimMode = 'auto';
        end

        % Menu selected function: TPanMenu
        function TPanMenuSelected(app, event)
   pan(app.Trace,'on');
        end

        % Menu selected function: TPointerMenu
        function TPointerMenuSelected(app, event)
   %    allowes a hotkey for reverting back to pointer
   zoom(app.Trace,'off')
   pan(app.Trace,'off')
        end

        % Button pushed function: BinarynamecopyButton
        function BinarynamecopyButtonPushed(app, event)
   clipboard('copy', [app.m.fP app.m.fN]);
   app.StatusLabel.Value = "Binary path copied to clipboard!";
        end

        % Button pushed function: SavenamecopyButton
        function SavenamecopyButtonPushed(app, event)
   if app.AddtimestampMenu.Checked
    timeStamp = "_"+datestr(now,'yymmddHHMM');
   else
    timeStamp = "";
   end
   sN = string(app.SavenameEditField.Value)+timeStamp + ".mat";
   clipboard('copy', app.savePath+"\"+sN);
   app.StatusLabel.Value = "Saved data path copied to clipboard!";
        end

        % Menu selected function: AddtimestampMenu
        function AddtimestampMenuSelected(app, event)
   if strcmp(app.AddtimestampMenu.Checked, 'on')
    app.AddtimestampMenu.Checked = 'off';
   else
    app.AddtimestampMenu.Checked = 'on';
   end
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
   message = sprintf("There's hotkeys for literally everything. Use them. \n" + ...
    "CTRL + Z: undo/redo the last action (but please save often!). \n" + ...
    "Add spikes: pressing this button will bring up a new figure in which to click on black unassigned spikes to add to your unit. \n" + ...
    "Force add spikes: add spikes selected in this process will be forced into the unit regardless of similarity. \n" + ...
    "Remove/split spikes: select traces from the left unit axes before pressing the button. \n" + ...
    "Merge units: bring up the units you want to merge on the left and right unit axes (left will be joined to the right). \n" + ...
    "Autosplit: this will do some PCA and bring up a window so you can check the results and decide yes/no. \n" + ...
    "Nice-fy unit numbers: will re-number units based on mean peak amplitude and refresh the plots. \n" + ...
    "Use Past and Future batches fields to pick the timerange of spikes that are shown in the unit plots. \n" + ...
    "You can load templates from other datasets and choose the location of template harvesting. \n" + ...
    "Scrub through unit: select the point in time at which to split/delete the spikes before/after. \n");
   uialert(app.UIBase,message,'Help','Icon','')
        end

        % Menu selected function: MatchunityaxisMenu
        function MatchunityaxisMenuSelected(app, event)
   y = ylim(app.LeftUnit);
   y(2,:) = ylim(app.RightUnit);
   yN(1) = min(y(:,1),[],1);
   yN(2) = max(y(:,2),[],1);
   ylim(app.LeftUnit, yN);
   ylim(app.RightUnit, yN);
        end

        % Value changed function: FuturebatchesField, 
        % PastbatchesField
        function PastbatchesFieldValueChanged(app, event)
   app.batchesPlotted = -round(app.PastbatchesField.Value):min([length(app.splitSizeList), round(app.FuturebatchesField.Value)]);
   app.redrawUnitPlots();
        end

        % Callback function: UnitfreqButton, UnitfrequencyMenu
        function UnitfreqButtonPushed(app, event)
   app.StatusLabel.Value = "Plotting unit time series...";
   drawnow
   f = figure; ax = axes; set(f, 'Position',  [200, 200, 900, 700]);
   for ii=1:length(app.s.clusters)
    iiCmap=app.cmap(ii,:);
    ms = app.getMarker(ii);
    numInBatch = zeros(1,length(app.splitSizeList));
    for jj = 1:length(app.splitSizeList)
     numInBatch(jj) = nnz(app.t.("spikeBatchNum_"+app.s.clusters(ii))==jj);
    end
    line(ax, 1:length(app.splitSizeList), numInBatch, 'Color', iiCmap,"Marker",ms);
   end
   
   xlabel(ax, "Batch number");
   ylabel(ax, "Frequency");
   title(ax, 'Spike frequency for each unit over time')
   legend(ax, "Unit " + app.s.clusters);
   app.StatusLabel.Value = "Ready";
   
        end

        % Button pushed function: RemoveplottedButton
        function RemoveplottedButtonPushed(app, event)
   value = app.LeftUnitDropDown.Value;
   if nnz(strcmp(value,app.s.clusters))
    I = app.wavesInBatches;
    if ~isempty(I)
     app.spikeRemover(value, I)
     app.redrawTracePlot();
     app.redrawUnitPlots();
    else
     app.StatusLabel.Value = "Plot empty!";
    end
   else
    app.StatusLabel.Value = "Left unit is empty!";
   end
        end

        % Value changed function: AmpthresholdEditField
        function AmpthresholdEditFieldValueChanged(app, event)
   value = app.AmpthresholdEditField.Value;
   if value < app.m.ampThreshold
    app.StatusLabel.Value = "Cannot decrease spike detection threshold! Only increasing is allowed!";
    app.AmpthresholdEditField.Value = app.m.ampThreshold;
    drawnow
   else
    app.m.ampThreshold = value;
    app.readFilter(-app.m.nChans*app.m.dbytes*size(app.xi,2),0);
    app.redrawTracePlot();
    app.redrawUnitPlots();
   end
        end

        % Button pushed function: SplitplottedButton
        function SplitplottedButtonPushed(app, event)
   value = app.LeftUnitDropDown.Value;
   if nnz(strcmp(value,app.s.clusters))
    I = app.wavesInBatches;
    if ~isempty(I)
     app.saveLast();
     app.unitSplitter(value, I)
     app.redrawTracePlot();
     app.redrawUnitPlots();
    else
     app.StatusLabel.Value = "Plot empty!";
    end
   else
    app.StatusLabel.Value = "Left unit is empty!";
   end
        end

        % Value changed function: NewField
        function NewFieldValueChanged(app, event)
   value = app.NewField.Value;
   app.m.newTempSTDThreshold = value;
        end

        % Value changed function: OldField
        function OldFieldValueChanged(app, event)
   value = app.OldField.Value;
   app.m.oldTempSTDThreshold = value;
        end

        % Button pushed function: ShowloadedtemplaatesButton
        function ShowloadedtemplaatesButtonPushed(app, event)
   if isfield(app.t, "importedTemplateMapping") && ~isempty(app.t.importedTemplateMapping)
    subplott = @(m,n,p) subtightplot (m, n, p, [0.03 0.03], [0.05 0.1], [0.05 0.05]);
    
    app.StatusLabel.Value = "Plotting units with their templates...";
    drawnow
    nUnitsWTemplates = size(app.t.importedTemplateMapping{2},1);
    sp = ceil(nUnitsWTemplates/2);
    f = figure; set(f, 'Position',  [200, 200, 900, 700]);
    ax = gobjects(nUnitsWTemplates*2,1);
    yTemp = zeros(nUnitsWTemplates*2,2);
    
    for ii=1:nUnitsWTemplates
     w = app.t.importedTemplateMapping{2}(ii,1);
     tW = app.t.importedTemplateMapping{2}(ii,2);
     ax(2*ii-1) = subplott(sp,4,2*ii-1);
     ax(2*ii) = subplott(sp,4,2*ii);
     ms = app.getMarker(ii);
     iiCmap=app.cmap(ii,:);
     
     tempWaves = app.s.("waves_"+w)(:,:,app.m.mainCh);
     if ~isempty(tempWaves)
      rp = randperm(size(tempWaves,1));
      if length(rp) > 600
       rp = rp(1:600);
      end
      tempWaves = tempWaves(sort(rp),:,:);
      p = line(ax(2*ii), -app.m.spikeWidth:app.m.spikeWidth, tempWaves');
      set(p, {'Color'}, num2cell(parula(size(tempWaves,1)),2));
     end
     
     tempWaves = app.t.("template_"+tW)(:,:,app.m.mainCh);
     if ~isempty(tempWaves)
      p = line(ax(2*ii-1), -app.m.spikeWidth:app.m.spikeWidth, tempWaves');
      set(p, {'Color'}, num2cell(parula(size(tempWaves,1)),2));
     end
     
     yTemp(2*ii,:) = ylim(ax(2*ii));
     yTemp(2*ii-1,:) = ylim(ax(2*ii-1));
     title(ax(2*ii), 'Unit '+w+" "+ ms +" ("+length(app.s.("unit_"+w))+")",'Color',iiCmap);
     title(ax(2*ii-1), 'Template '+tW);
    end
    
    sgtitle('Units with their loaded templates - max 600 random spikes plotted');
    yTemp = [min(yTemp(:,1)), max(yTemp(:,2))];
    for ii = 1:length(ax)
     ylim(ax(ii), yTemp);
     ylim(yTemp); %axis square;
     xlim(ax(ii), [-app.m.spikeWidth app.m.spikeWidth]);
     yticks(ax(ii), 200*floor(yTemp(1)/200):200:200*ceil(yTemp(2)/200));
     set(ax(ii),'xTick',[], 'YGrid', 'on', 'XGrid', 'off');
    end
    
    app.StatusLabel.Value = "Ready";
   else
    app.StatusLabel.Value = "There are no loaded templates OR loaded templates are not used!";
   end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIBase = uifigure('Visible', 'off');
            app.UIBase.Name = 'Spike sorting extraordinaire';
            app.UIBase.CloseRequestFcn = createCallbackFcn(app, @UIBaseCloseRequest, true);
            app.SessionMenu = uimenu(app.UIBase);
            app.SessionMenu.Text = 'Session';
            app.InitialiseMenu = uimenu(app.SessionMenu);
            app.InitialiseMenu.MenuSelectedFcn = createCallbackFcn(app, @InitialiseButtonPushed, true);
            app.InitialiseMenu.Text = 'Initialise';
            app.LoadMenu = uimenu(app.SessionMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadMenu.Text = 'Load';
            app.LoadtemplatesMenu = uimenu(app.SessionMenu);
            app.LoadtemplatesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadtemplatesMenuSelected, true);
            app.LoadtemplatesMenu.Text = 'Load templates';
            app.SaveMenu = uimenu(app.SessionMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveMenu.Text = 'Save';
            app.AddtimestampMenu = uimenu(app.SessionMenu);
            app.AddtimestampMenu.MenuSelectedFcn = createCallbackFcn(app, @AddtimestampMenuSelected, true);
            app.AddtimestampMenu.Checked = 'on';
            app.AddtimestampMenu.Text = 'Add timestamp to save';
            app.UndoredoMenu = uimenu(app.SessionMenu);
            app.UndoredoMenu.MenuSelectedFcn = createCallbackFcn(app, @UndoredoMenuSelected, true);
            app.UndoredoMenu.Text = 'Undo/redo';
            app.TimelineMenu = uimenu(app.UIBase);
            app.TimelineMenu.Text = 'Timeline';
            app.PreviousbatchMenu = uimenu(app.TimelineMenu);
            app.PreviousbatchMenu.MenuSelectedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PreviousbatchMenu.Text = 'Previous batch';
            app.NextbatchMenu = uimenu(app.TimelineMenu);
            app.NextbatchMenu.MenuSelectedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextbatchMenu.Text = 'Next batch';
            app.UnitfrequencyMenu = uimenu(app.TimelineMenu);
            app.UnitfrequencyMenu.MenuSelectedFcn = createCallbackFcn(app, @UnitfreqButtonPushed, true);
            app.UnitfrequencyMenu.Text = 'Show unit frequency time series';
            app.ScrubthroughunitMenu = uimenu(app.TimelineMenu);
            app.ScrubthroughunitMenu.MenuSelectedFcn = createCallbackFcn(app, @UnitscrubButtonPushed, true);
            app.ScrubthroughunitMenu.Text = 'Scrub through unit';
            app.UnitMenu = uimenu(app.UIBase);
            app.UnitMenu.Text = 'Unit';
            app.NewunitMenu = uimenu(app.UnitMenu);
            app.NewunitMenu.MenuSelectedFcn = createCallbackFcn(app, @NewunitButtonPushed, true);
            app.NewunitMenu.Text = 'New unit';
            app.AddspikeMenu = uimenu(app.UnitMenu);
            app.AddspikeMenu.MenuSelectedFcn = createCallbackFcn(app, @AddspikeButtonPushed, true);
            app.AddspikeMenu.Text = 'Add spike to left unit';
            app.ForceaddMenu = uimenu(app.UnitMenu);
            app.ForceaddMenu.MenuSelectedFcn = createCallbackFcn(app, @AddspikeButtonPushed, true);
            app.ForceaddMenu.Text = 'Force add spike to left unit';
            app.RemovespikeMenu = uimenu(app.UnitMenu);
            app.RemovespikeMenu.MenuSelectedFcn = createCallbackFcn(app, @RemovespikeButtonPushed, true);
            app.RemovespikeMenu.Text = 'Remove spike from left unit';
            app.SplitMenu = uimenu(app.UnitMenu);
            app.SplitMenu.MenuSelectedFcn = createCallbackFcn(app, @SplitButtonPushed, true);
            app.SplitMenu.Text = 'Split left unit';
            app.MergeMenu = uimenu(app.UnitMenu);
            app.MergeMenu.MenuSelectedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeMenu.Text = 'Merge units';
            app.AutosplitMenu = uimenu(app.UnitMenu);
            app.AutosplitMenu.MenuSelectedFcn = createCallbackFcn(app, @AutosplitButtonPushed, true);
            app.AutosplitMenu.Text = 'Autosplit left unit';
            app.RefinebatchMenu = uimenu(app.UnitMenu);
            app.RefinebatchMenu.MenuSelectedFcn = createCallbackFcn(app, @RefinebatchButtonPushed, true);
            app.RefinebatchMenu.Text = 'Refine batch';
            app.FigureshortcutsMenu = uimenu(app.UIBase);
            app.FigureshortcutsMenu.Text = 'Figure shortcuts';
            app.LZoomMenu = uimenu(app.FigureshortcutsMenu);
            app.LZoomMenu.MenuSelectedFcn = createCallbackFcn(app, @LZoomMenuSelected, true);
            app.LZoomMenu.Text = 'Left unit: zoom';
            app.LResetMenu = uimenu(app.FigureshortcutsMenu);
            app.LResetMenu.MenuSelectedFcn = createCallbackFcn(app, @LResetMenuSelected, true);
            app.LResetMenu.Text = 'Left unit: reset zoom';
            app.LPanMenu = uimenu(app.FigureshortcutsMenu);
            app.LPanMenu.MenuSelectedFcn = createCallbackFcn(app, @LPanMenuSelected, true);
            app.LPanMenu.Text = 'Left unit: pan';
            app.LPointerMenu = uimenu(app.FigureshortcutsMenu);
            app.LPointerMenu.MenuSelectedFcn = createCallbackFcn(app, @LPointerMenuSelected, true);
            app.LPointerMenu.Text = 'Left unit: pointer';
            app.TZoomMenu = uimenu(app.FigureshortcutsMenu);
            app.TZoomMenu.MenuSelectedFcn = createCallbackFcn(app, @TZoomMenuSelected, true);
            app.TZoomMenu.Text = 'Trace: zoom';
            app.TResetMenu = uimenu(app.FigureshortcutsMenu);
            app.TResetMenu.MenuSelectedFcn = createCallbackFcn(app, @TResetMenuSelected, true);
            app.TResetMenu.Text = 'Trace: reset zoom';
            app.TPanMenu = uimenu(app.FigureshortcutsMenu);
            app.TPanMenu.MenuSelectedFcn = createCallbackFcn(app, @TPanMenuSelected, true);
            app.TPanMenu.Text = 'Trace: pan';
            app.TPointerMenu = uimenu(app.FigureshortcutsMenu);
            app.TPointerMenu.MenuSelectedFcn = createCallbackFcn(app, @TPointerMenuSelected, true);
            app.TPointerMenu.Text = 'Trace: pointer';
            app.MatchunityaxisMenu = uimenu(app.FigureshortcutsMenu);
            app.MatchunityaxisMenu.MenuSelectedFcn = createCallbackFcn(app, @MatchunityaxisMenuSelected, true);
            app.MatchunityaxisMenu.Text = 'Match unit Y-axis';
            app.OthersMenu = uimenu(app.UIBase);
            app.OthersMenu.Text = 'Others';
            app.HelpMenu = uimenu(app.OthersMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.GridMain = uigridlayout(app.UIBase);
            app.StatusLabel = uieditfield(app.GridMain, 'text');
            app.StatusLabel.Editable = 'off';
            app.StatusLabel.Value = 'Ready';
            app.ControlsGrid = uigridlayout(app.GridMain);
            app.ThresholdsGrid = uigridlayout(app.ControlsGrid);
            app.ThresholdsLabel = uilabel(app.ThresholdsGrid);
            app.ThresholdsLabel.Text = 'Thresholding';
            app.CurrentunitsLabel = uilabel(app.ThresholdsGrid);
            app.CurrentunitsLabel.Text = 'Current units';
            app.OldField = uieditfield(app.ThresholdsGrid, 'numeric');
            app.OldField.ValueChangedFcn = createCallbackFcn(app, @OldFieldValueChanged, true);
            app.OldField.Value = 1;
            app.NewField = uieditfield(app.ThresholdsGrid, 'numeric');
            app.NewField.ValueChangedFcn = createCallbackFcn(app, @NewFieldValueChanged, true);
            app.NewField.Value = 1;
            app.NewunitLabel = uilabel(app.ThresholdsGrid);
            app.NewunitLabel.Text = 'New unit';
            app.SpikedetectionEditFieldLabel = uilabel(app.ThresholdsGrid);
            app.SpikedetectionEditFieldLabel.Text = 'Spike detection';
            app.AmpthresholdEditField = uieditfield(app.ThresholdsGrid, 'numeric');
            app.AmpthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @AmpthresholdEditFieldValueChanged, true);
            app.FuzzyButton = uibutton(app.ThresholdsGrid, 'state');
            app.FuzzyButton.Text = 'Fuzzy thresholding';
            app.FuzzyButton.Value = true;
            app.TraceylimitLabel = uilabel(app.ThresholdsGrid);
            app.TraceylimitLabel.Text = 'Trace y-limit';
            app.yLimLowField = uieditfield(app.ThresholdsGrid, 'numeric');
            app.yLimLowField.Value = -Inf;
            app.yLimHighField = uieditfield(app.ThresholdsGrid, 'numeric');
            app.yLimHighField.Value = Inf;
            app.DatascrubGrid = uigridlayout(app.ControlsGrid);
            app.ScrubdataLabel = uilabel(app.DatascrubGrid);
            app.ScrubdataLabel.Text = 'Scrub data';
            app.PreviousbatchButton = uibutton(app.DatascrubGrid, 'push');
            app.PreviousbatchButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PreviousbatchButton.Text = 'Prev (O)';
            app.NextbatchButton = uibutton(app.DatascrubGrid, 'push');
            app.NextbatchButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextbatchButton.Text = 'Next (P)';
            app.AutosortButton = uibutton(app.DatascrubGrid, 'push');
            app.AutosortButton.ButtonPushedFcn = createCallbackFcn(app, @AutosortButtonPushed, true);
            app.AutosortButton.Text = 'Autosort to:';
            app.uptoField = uieditfield(app.DatascrubGrid, 'numeric');
            app.uptoField.Value = 1;
            app.ResortbatchButton = uibutton(app.DatascrubGrid, 'push');
            app.ResortbatchButton.ButtonPushedFcn = createCallbackFcn(app, @ResortbatchButtonPushed, true);
            app.ResortbatchButton.Text = 'Resort batch';
            app.RefinebatchButton = uibutton(app.DatascrubGrid, 'push');
            app.RefinebatchButton.ButtonPushedFcn = createCallbackFcn(app, @RefinebatchButtonPushed, true);
            app.RefinebatchButton.Text = 'Refine batch (X)';
            app.GotobatchButton = uibutton(app.DatascrubGrid, 'push');
            app.GotobatchButton.ButtonPushedFcn = createCallbackFcn(app, @GotobatchButtonPushed, true);
            app.GotobatchButton.Text = 'Go to batch:';
            app.GotobatchField = uieditfield(app.DatascrubGrid, 'numeric');
            app.GotobatchField.Value = 1;
            app.UnitoptionsGrid = uigridlayout(app.ControlsGrid);
            app.UnitmanipulationLabel = uilabel(app.UnitoptionsGrid);
            app.UnitmanipulationLabel.Text = 'Unit manipulation';
            app.DeleteunitButton = uibutton(app.UnitoptionsGrid, 'push');
            app.DeleteunitButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteunitButtonPushed, true);
            app.DeleteunitButton.Text = 'Delete unit';
            app.AddspikeButton = uibutton(app.UnitoptionsGrid, 'push');
            app.AddspikeButton.ButtonPushedFcn = createCallbackFcn(app, @AddspikeButtonPushed, true);
            app.AddspikeButton.Text = 'Add spike (D)';
            app.RemovespikeButton = uibutton(app.UnitoptionsGrid, 'push');
            app.RemovespikeButton.ButtonPushedFcn = createCallbackFcn(app, @RemovespikeButtonPushed, true);
            app.RemovespikeButton.Text = 'Remove spike (F)';
            app.AutosplitButton = uibutton(app.UnitoptionsGrid, 'push');
            app.AutosplitButton.ButtonPushedFcn = createCallbackFcn(app, @AutosplitButtonPushed, true);
            app.AutosplitButton.Text = 'Autosplit into (A):';
            app.AutosplitField = uieditfield(app.UnitoptionsGrid, 'numeric');
            app.AutosplitField.Value = 3;
            app.SplitunitButton = uibutton(app.UnitoptionsGrid, 'push');
            app.SplitunitButton.ButtonPushedFcn = createCallbackFcn(app, @SplitButtonPushed, true);
            app.SplitunitButton.Text = 'Split unit (C)';
            app.MergeButton = uibutton(app.UnitoptionsGrid, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Text = 'Merge (V)';
            app.UnitscrubButton = uibutton(app.UnitoptionsGrid, 'push');
            app.UnitscrubButton.ButtonPushedFcn = createCallbackFcn(app, @UnitscrubButtonPushed, true);
            app.UnitscrubButton.Text = 'Scrub through unit';
            app.RemoveplottedButton = uibutton(app.UnitoptionsGrid, 'push');
            app.RemoveplottedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveplottedButtonPushed, true);
            app.RemoveplottedButton.Text = 'Remove plotted';
            app.SplitplottedButton = uibutton(app.UnitoptionsGrid, 'push');
            app.SplitplottedButton.ButtonPushedFcn = createCallbackFcn(app, @SplitplottedButtonPushed, true);
            app.SplitplottedButton.Text = 'Split plotted';
            app.NewunitButton = uibutton(app.UnitoptionsGrid, 'push');
            app.NewunitButton.ButtonPushedFcn = createCallbackFcn(app, @NewunitButtonPushed, true);
            app.NewunitButton.Text = 'New unit (N)';
            app.ForceaddButton = uibutton(app.UnitoptionsGrid, 'push');
            app.ForceaddButton.ButtonPushedFcn = createCallbackFcn(app, @AddspikeButtonPushed, true);
            app.ForceaddButton.Text = 'Force add (G)';
            app.AutosplitBatchButton = uibutton(app.UnitoptionsGrid, 'push');
            app.AutosplitBatchButton.ButtonPushedFcn = createCallbackFcn(app, @AutosplitBatchButtonPushed, true);
            app.AutosplitBatchButton.Text = 'Autosplit batch into:';
            app.UnitviewingGrid = uigridlayout(app.ControlsGrid);
            app.SpikestotalLabel = uilabel(app.UnitviewingGrid);
            app.SpikestotalLabel.Text = '# Spikes total';
            app.SpikeshownField = uieditfield(app.UnitviewingGrid, 'numeric');
            app.SpikeshownField.ValueChangedFcn = createCallbackFcn(app, @SpikeshownFieldValueChanged, true);
            app.SpikeshownField.Value = 300;
            app.PastbatchesLabel = uilabel(app.UnitviewingGrid);
            app.PastbatchesLabel.Text = 'Past batches';
            app.PastbatchesField = uieditfield(app.UnitviewingGrid, 'numeric');
            app.PastbatchesField.ValueChangedFcn = createCallbackFcn(app, @PastbatchesFieldValueChanged, true);
            app.PastbatchesField.Value = 3;
            app.UnitviewingLabel = uilabel(app.UnitviewingGrid);
            app.UnitviewingLabel.Text = 'Unit viewing';
            app.FuturebatchesLabel = uilabel(app.UnitviewingGrid);
            app.FuturebatchesLabel.Text = 'Future batches';
            app.FuturebatchesField = uieditfield(app.UnitviewingGrid, 'numeric');
            app.FuturebatchesField.ValueChangedFcn = createCallbackFcn(app, @PastbatchesFieldValueChanged, true);
            app.PlotallchButton = uibutton(app.UnitviewingGrid, 'state');
            app.PlotallchButton.Text = 'Plot AllCh';
            app.OtheroptionsGrid = uigridlayout(app.ControlsGrid);
            app.OthersLabel = uilabel(app.OtheroptionsGrid);
            app.OthersLabel.Text = 'Others';
            app.CleanupButton = uibutton(app.OtheroptionsGrid, 'push');
            app.CleanupButton.ButtonPushedFcn = createCallbackFcn(app, @CleanupButtonPushed, true);
            app.CleanupButton.Text = 'Nice-fy unit numbers';
            app.ShowallButton = uibutton(app.OtheroptionsGrid, 'push');
            app.ShowallButton.ButtonPushedFcn = createCallbackFcn(app, @ShowallButtonButtonPushed, true);
            app.ShowallButton.Text = 'Show unit overview';
            app.UnitfreqButton = uibutton(app.OtheroptionsGrid, 'push');
            app.UnitfreqButton.ButtonPushedFcn = createCallbackFcn(app, @UnitfreqButtonPushed, true);
            app.UnitfreqButton.Text = 'Unit freq time series';
            app.ShowloadedtemplaatesButton = uibutton(app.OtheroptionsGrid, 'push');
            app.ShowloadedtemplaatesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowloadedtemplaatesButtonPushed, true);
            app.ShowloadedtemplaatesButton.Text = 'Show loaded templates';
            app.LGrid = uigridlayout(app.GridMain);
            app.LeftUnitDropDown = uidropdown(app.LGrid);
            app.LeftUnitDropDown.Items = {'1'};
            app.LeftUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @LeftUnitDropDownValueChanged, true);
            app.LeftUnitDropDown.Value = '1';
            app.LTab = uitabgroup(app.LGrid);
            app.MainChLTab = uitab(app.LTab);
            app.MainChLTab.Title = 'MainCh';
            app.GridMainChLTab = uigridlayout(app.MainChLTab);
            app.LeftUnit = uiaxes(app.GridMainChLTab);
            title(app.LeftUnit, '')
            xlabel(app.LeftUnit, 'Samples')
            ylabel(app.LeftUnit, 'Amplitude (uV)')
            app.LeftUnit.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.AllChLTab = uitab(app.LTab);
            app.AllChLTab.Title = 'AllCh';
            app.RGrid = uigridlayout(app.GridMain);
            app.RightUnitDropDown = uidropdown(app.RGrid);
            app.RightUnitDropDown.Items = {'1'};
            app.RightUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @RightUnitDropDownValueChanged, true);
            app.RightUnitDropDown.Value = '1';
            app.RTab = uitabgroup(app.RGrid);
            app.MainChRTab = uitab(app.RTab);
            app.MainChRTab.Title = 'MainCh';
            app.GridMainChRTab = uigridlayout(app.MainChRTab);
            app.RightUnit = uiaxes(app.GridMainChRTab);
            title(app.RightUnit, '')
            xlabel(app.RightUnit, 'Samples')
            ylabel(app.RightUnit, 'Amplitude (uV)')
            app.RightUnit.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.AllChRTab = uitab(app.RTab);
            app.AllChRTab.Title = 'AllCh';
            app.InfoGrid = uigridlayout(app.GridMain);
            app.BinaryEditField = uieditfield(app.InfoGrid, 'text');
            app.BinaryEditField.Editable = 'off';
            app.BinaryEditFieldLabel = uilabel(app.InfoGrid);
            app.BinaryEditFieldLabel.Text = 'Binary';
            app.SavenameEditFieldLabel = uilabel(app.InfoGrid);
            app.SavenameEditFieldLabel.Text = 'Savename';
            app.SavenameEditField = uieditfield(app.InfoGrid, 'text');
            app.BinarynamecopyButton = uibutton(app.InfoGrid, 'push');
            app.BinarynamecopyButton.ButtonPushedFcn = createCallbackFcn(app, @BinarynamecopyButtonPushed, true);
            app.BinarynamecopyButton.Text = 'Copy';
            app.SavenamecopyButton = uibutton(app.InfoGrid, 'push');
            app.SavenamecopyButton.ButtonPushedFcn = createCallbackFcn(app, @SavenamecopyButtonPushed, true);
            app.SavenamecopyButton.Text = 'Copy';
            app.TTab = uitabgroup(app.GridMain);
            app.MainChTTab = uitab(app.TTab);
            app.MainChTTab.Title = 'MainCh';
            app.GridMainChTTab = uigridlayout(app.MainChTTab);
            app.Trace = uiaxes(app.GridMainChTTab);
            title(app.Trace, 'Data Trace')
            xlabel(app.Trace, 'Time (ms)')
            ylabel(app.Trace, 'Amplitude (uV)')
            app.AllChTTab = uitab(app.TTab);
            app.AllChTTab.Title = 'AllCh';
            % Show the figure after all components are created
            app.UIBase.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spikeSortGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIBase)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIBase)
        end
    end
end